classdef UserInterface < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        TabGroup                    matlab.ui.container.TabGroup
        InputsTab                   matlab.ui.container.Tab
        SelectExcelFileButton       matlab.ui.control.Button
        ExcelFilewithInputDataEditField  matlab.ui.control.EditField
        nVEditFieldLabel            matlab.ui.control.Label
        nVEditField                 matlab.ui.control.NumericEditField
        nPEditFieldLabel            matlab.ui.control.Label
        nPEditField                 matlab.ui.control.NumericEditField
        ProgramStatusLampLabel      matlab.ui.control.Label
        ProgramStatusLamp           matlab.ui.control.Lamp
        AnalyzeCaseButton           matlab.ui.control.Button
        NoEVResultsTab              matlab.ui.container.Tab
        SecurityParameters          matlab.ui.container.TabGroup
        RealPowerTab                matlab.ui.container.Tab
        PUITable                    matlab.ui.control.Table
        ReactivePowerTab            matlab.ui.container.Tab
        QUITable                    matlab.ui.control.Table
        AMWCOTab                    matlab.ui.container.Tab
        AMWCOUITable                matlab.ui.control.Table
        APCOTab                     matlab.ui.container.Tab
        APCOUITable                 matlab.ui.control.Table
        SIvTab                      matlab.ui.container.Tab
        SIvUITable                  matlab.ui.control.Table
        SIpTab                      matlab.ui.container.Tab
        SIpUITable                  matlab.ui.control.Table
        OthersTab                   matlab.ui.container.Tab
        SysAMWCOEditFieldLabel      matlab.ui.control.Label
        SysAMWCOEditField           matlab.ui.control.NumericEditField
        TSIEditFieldLabel           matlab.ui.control.Label
        TSIEditField                matlab.ui.control.NumericEditField
        MaxMVAUITable               matlab.ui.control.Table
        MaxMVACTGUITable            matlab.ui.control.Table
        EditFieldLabel              matlab.ui.control.EditField
        EditFieldLabel_2            matlab.ui.control.EditField
        EVResultsTab                matlab.ui.container.Tab
        SecurityParametersEV        matlab.ui.container.TabGroup
        RealPowerTabEV              matlab.ui.container.Tab
        PUITableEV                  matlab.ui.control.Table
        ReactivePowerTabEV          matlab.ui.container.Tab
        QUITableEV                  matlab.ui.control.Table
        AMWCOTabEV                  matlab.ui.container.Tab
        AMWCOUITableEV              matlab.ui.control.Table
        APCOTabEV                   matlab.ui.container.Tab
        APCOUITableEV               matlab.ui.control.Table
        SIvTabEV                    matlab.ui.container.Tab
        SIvUITableEV                matlab.ui.control.Table
        SIpTabEV                    matlab.ui.container.Tab
        SIpUITableEV                matlab.ui.control.Table
        SingleParametersTabEV       matlab.ui.container.Tab
        SysAMWCOEditField_2Label    matlab.ui.control.Label
        SysAMWCOEditFieldEV         matlab.ui.control.NumericEditField
        TSIEditField_2Label         matlab.ui.control.Label
        TSIEditFieldEV              matlab.ui.control.NumericEditField
        MaxMVAUITableEV             matlab.ui.control.Table
        MaxMVACTGUITableEV          matlab.ui.control.Table
        EditFieldLabel_3            matlab.ui.control.EditField
        EditFieldLabel_4            matlab.ui.control.EditField
        ComparisonsTab              matlab.ui.container.Tab
        SecurityParametersCOMP      matlab.ui.container.TabGroup
        AMWCOTabABS                 matlab.ui.container.Tab
        AMWCOUITableABS             matlab.ui.control.Table
        APCOTabABS                  matlab.ui.container.Tab
        APCOUITableABS              matlab.ui.control.Table
        SIvTabABS                   matlab.ui.container.Tab
        SIvUITableABS               matlab.ui.control.Table
        SIpTabABS                   matlab.ui.container.Tab
        SIpUITableABS               matlab.ui.control.Table
        SingleParametersTabCOMP     matlab.ui.container.Tab
        TSIEditField_2Label_2       matlab.ui.control.Label
        TSIEditFieldABS             matlab.ui.control.NumericEditField
        SysAMWCOLabel               matlab.ui.control.Label
        SysAMWCOEditFieldPCT        matlab.ui.control.NumericEditField
        TSILabel                    matlab.ui.control.Label
        TSIEditFieldPCT             matlab.ui.control.NumericEditField
        MaxMVAUITableABS            matlab.ui.control.Table
        MaxMVAUITablePCT            matlab.ui.control.Table
        SysAMWCOEditField_2Label_2  matlab.ui.control.Label
        SysAMWCOEditFieldABS        matlab.ui.control.NumericEditField
        EditFieldLabel_5            matlab.ui.control.EditField
        EditFieldLabel_6            matlab.ui.control.EditField
        AMWCOTabPCT                 matlab.ui.container.Tab
        AMWCOUITablePCT             matlab.ui.control.Table
        APCOTabPCT                  matlab.ui.container.Tab
        APCOUITablePCT              matlab.ui.control.Table
        SIVTabPCT                   matlab.ui.container.Tab
        SIvUITablePCT               matlab.ui.control.Table
        SIpTab_2                    matlab.ui.container.Tab
        SIpUITablePCT               matlab.ui.control.Table
        LinePieChartsTab            matlab.ui.container.Tab
        PieChartTabGroup            matlab.ui.container.TabGroup
        NoEVsTabPie                 matlab.ui.container.Tab
        EVsTabPie                   matlab.ui.container.Tab
        LineBarGraphsTab            matlab.ui.container.Tab
        BarGraphTabGroup            matlab.ui.container.TabGroup
        NoEVsTabBG                  matlab.ui.container.Tab
        UIAxesNoEVs                 matlab.ui.control.UIAxes
        EVsTabBG                    matlab.ui.container.Tab
        UIAxesEVs                   matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        FILE % Input Data Excel Spreadsheet File Object
        PATH % Input Data Excel Spreadhseet Path Object
    end
    
    methods (Access = private)
        
        function [Zmat,BusType,Knowns,Pg,Pd,Qg,Qd,EVd,Slim,wv,Vsp,dV,wp,Plim]=...
                        ReadExcel(app,Excel_File)

        %{
            Excel_File: File name of Excel spreadhseet where main function inputs
            are stored. Each input must be in its own sheet and the sheet name must
            match the variable name. The Excel spreadsheet must be in the current
            Matlab directory and Excel_File must be input as a string.
        %}
            
            j=sqrt(-1);
                        
            Zmatr=xlsread(Excel_File,'Zmatr'); 
            Zmatc=xlsread(Excel_File,'Zmatc'); 
            Zmat=Zmatr+j.*Zmatc;
            
            BusType=xlsread(Excel_File,'BusType'); 
            Knowns=xlsread(Excel_File,'Knowns'); 
            Pg=xlsread(Excel_File,'Pg');
            Qg=xlsread(Excel_File,'Qg');
            Pd=xlsread(Excel_File,'Pd');
            Qd=xlsread(Excel_File,'Qd');
            EVd=xlsread(Excel_File,'EVd');
            Slim=xlsread(Excel_File,'Slim');
            Plim=xlsread(Excel_File,'Plim');
            wv=xlsread(Excel_File,'wv');
            wp=xlsread(Excel_File,'wp');
            Vsp=xlsread(Excel_File,'Vsp');
            dV=xlsread(Excel_File,'dV');
        
        end
        
        function admittance=MakeY(app,impedance)
        %{
        Calculates admittance from an impedance.
        
        impedance: complex impedance of the form r+jx.
        admittance: compled admittance of the form r/(r^2+x^2)-j*x/(r^2+x^2). This
            is the inverse of the impedance.
        %}
        
        j=sqrt(-1);
        r=abs(real(impedance));
        x=abs(imag(impedance));
        
        if(abs(impedance)~=0)
            admittance=r/(r^2+x^2)-j*x/(r^2+x^2);
        else 
            admittance=0;
        end
        
        end
        
        function Ybus=MakeYbus(app,Zmat)
        %{
        Calculates Ybus matrix from an input matrix of line impedances.
         
        Zmat: Matrix of line impedances connecting busses. Diagonal entries are
            ignored. Zij is the impedance of the line connecting busses i and j.
            If no line connects busses i and j, Zij is 0.
        Ybus: Bus admittance matrix.
        %}
        
        [nBus,~]=size(Zmat);
        Ybus=zeros(nBus);
        
        %Find diagonal entries of Ybus
        for r=1:1:nBus
            row=Zmat(r,:);
            diagonal_element=0;
            for c=1:1:nBus                
                    impedance=row(c);
                    admittance=MakeY(app,impedance);
                    diagonal_element=diagonal_element+admittance;                         
            end
            diagonal_element=diagonal_element+Zmat(r,r);%accounts for capacitances
            Ybus(r,r)=diagonal_element;
        end
        
        %Find off-diagonal entries of Ybus
        for r=1:1:nBus
            row=Zmat(r,:);
            for c=1:1:nBus
                if(c~=r && Ybus(r,c)==0)
                    impedance=row(c);
                    admittance=MakeY(app,impedance);
                    Ybus(r,c)=-admittance;            
                end
            end    
        end
        
        end
        
        function f=MakeF(app,nBus,pMark,qMark,fP,fQ)
        %{
        pMark: vector of 0/1s indicating which Ps are equations in f
        qMark: vector of 0/1s indicating which Qs are equations in f
        fP: vector of P power balance equations in symbolic representation
        fQ: vector of Q power balance equations in symbolic representation
        %}
        
        fa=[];fb=[];
        for i=1:1:nBus
            if (pMark(i)==1)
                fa=[fa;fP(i)];
            end
            if (qMark(i)==1)
                fb=[fb;fQ(i)];
            end
        end
        f=[fa;fb];
        
        end
        
        function [x,out_flag]=FindX(app,jacobian,f,Theta,th_indices,V,v_indices,x,rV,rTh)
        %{
        jacobian: jacobian in symbolic representation
        f: f vector in symbolic representation
        Theta: theta vector in symbolic representation
        th_indices: vector of 0/1s indicating which thetas are variables in x
        V: v vector in symbolic representation
        v_indices: vector of 0/1s indicating which Vs are variables in x
        x: initalized vector x
        rV: number of voltages in x
        rTh: number of thetas in x
        %}
        
        %Iterate to solve for x
        [rJ,cJ]=size(jacobian);
        [rF,~]=size(f);
        out_flag=0;
        epsilon=1e6;
        threshold=1e-3;
        ctr=0;
        while epsilon>threshold
            %evaluate jacobian at x
            jacob_copy=jacobian;
            for i=1:1:rJ
                for j=1:1:cJ
                    %substitute current Theta guesses
                    for k=1:1:rTh
                        jacob_copy(i,j)=subs(jacob_copy(i,j),...
                            Theta(th_indices(k)),x(k));
                    end
                    %substitute current V guesses
                    for k=1:1:rV
                        jacob_copy(i,j)=subs(jacob_copy(i,j),...
                            V(v_indices(k)),x(k+rTh));
                    end
                end
            end
            %evaluate f at x
            f_copy=f;
            for i=1:1:rF
                %substitute current Theta guesses
                for j=1:1:rTh
                    f_copy(i,1)=subs(f_copy(i,1),...
                    Theta(th_indices(j)),x(j));
                end
                %substitute current V guesses
                for j=1:1:rV
                    f_copy(i,1)=subs(f_copy(i,1),...
                    V(v_indices(j)),x(j+rTh));
                end                
            end
            
            %solve for next x and prepare next iteration
            jacob_copy=double(jacob_copy);
            f_copy=double(f_copy);
            x=double(x);
            x=x-inv(jacob_copy)*f_copy;
            epsilon=max(abs(f_copy));
            ctr=ctr+1;
            if (ctr>15)
                threshold=0.5;
                out_flag=1;
            end
        end

        end
        
        function Jacobian=MakeJacobian(app,nBus,pMark,qMark,thMark,vMark,fP,fQ,V,...
            Theta)
        %{
            nBus: number of busses in system
            pMark: vector of 0/1s indicating which Ps are equations in f
            qMark: vector of 0/1s indicating which Qs are equations in f
            thMark: vector of 0/1s indicating which Thetas are variables in x
            vMark: vector of 0/1s indicating which Vs are variables in x
            fP: real power portions of vector f in symbolic representation
            fQ: rective power portions of vector f in symbolic representation
            V: vector of partially-solved symbolic bus voltages
            Theta: vector of partially-solved symbolic bus angles (degrees) 
        %}
        
        Jacobian=[];
        for i=1:1:nBus
            if (pMark(i)==1)
                row=[];
                for j=1:1:nBus
                    if (thMark(j)==1)
                        row=[row,diff(fP(i),Theta(j))];
                    end
                end
                for j=1:1:nBus
                    if (vMark(j)==1)
                        row=[row,diff(fP(i),V(j))];
                    end
                end
                Jacobian=[Jacobian;row];
            end
        end
        
        for i=1:1:nBus
            if (qMark(i)==1)
                row=[];
                for j=1:1:nBus
                    if (thMark(j)==1)
                        row=[row,diff(fQ(i),Theta(j))];
                    end
                end
                for j=1:1:nBus
                    if (vMark(j)==1)
                        row=[row,diff(fQ(i),V(j))];
                    end
                end
                Jacobian=[Jacobian;row];
            end
        end
        
        end
        
        function [fP,fQ]=MakePowerBalanceEquation(app,nBus,fP,fQ,Pg,Pd,EVd,Qg,Qd,V,Theta,...
            B,G,BusType)
        %{
            nBus: number of busses in the system
            fP: real power portions of vector f in symbolic representation
            fQ: rective power portions of vector f in symbolic representation
            Pg: Column vector of generation at each bus in MW. 0 if no generators 
            at the bus
            Pd: Column vector of load demand at each bus in MW. 0 if no demand at
                the bus. Excludes EV loading
            Qg: Column vector of generation at each bus in MVar. 0 if no generators
                at the bus
            Qd: Column vector of load demand at each bus in MVar. 0 if no demand at
                the bus. Excludes EV loading
            V: vector of partially-solved symbolic bus voltages
            Theta: vector of partially-solved symbolic bus angles (degrees)
            B: imaginary portion of Ybus
            G: real portion of Ybus
            BusType: Integer column vector indicating the type of each bus. The ith
                entry corresponds to the ith bus. Slack = 1, PQ = 2, PV = 3
        %}
        for i=1:1:nBus
            bus=BusType(i);    
            if (bus==2)%PQ bus
                fP(i)=-Pg(i)+Pd(i)+EVd(i);%EVs as demand only
                fQ(i)=-Qg(i)+Qd(i);
                for k=1:1:nBus
                    fP(i)=V(i)*V(k)*(G(i,k)*cos(Theta(i)-Theta(k))+B(i,k)*...
                        sin(Theta(i)-Theta(k)))+fP(i);
                    fQ(i)=V(i)*V(k)*(G(i,k)*sin(Theta(i)-Theta(k))-B(i,k)*...
                        cos(Theta(i)-Theta(k)))+fQ(i);
                end        
            elseif (bus==3)%PV bus
                fP(i)=-Pg(i)+Pd(i)+EVd(i);
                for k=1:1:nBus
                    fP(i)=V(i)*V(k)*(G(i,k)*cos(Theta(i)-Theta(k))+B(i,k)*...
                        sin(Theta(i)-Theta(k)))+fP(i);            
                end        
            end
        end
        
        end
        
        function [fP,fQ]=MakePowerBalanceEquationEVs(app,nBus,fP,fQ,Pg,Pd,EVd,Qg,Qd,...
            V,Theta,B,G,BusType)
        %{
            nBus: number of busses in the system
            fP: real power portions of vector f in symbolic representation
            fQ: rective power portions of vector f in symbolic representation
            Pg: Column vector of generation at each bus in MW. 0 if no generators 
            at the bus
            Pd: Column vector of load demand at each bus in MW. 0 if no demand at
                the bus. Excludes EV loading
            Qg: Column vector of generation at each bus in MVar. 0 if no generators
                at the bus
            Qd: Column vector of load demand at each bus in MVar. 0 if no demand at
                the bus. Excludes EV loading
            EVd: Column vector of EV load demand at each bus in MW (assumed purely
                 real)
            V: vector of partially-solved symbolic bus voltages
            Theta: vector of partially-solved symbolic bus angles (degrees)
            B: imaginary portion of Ybus
            G: real portion of Ybus
            BusType: Integer column vector indicating the type of each bus. The ith
                entry corresponds to the ith bus. Slack = 1, PQ = 2, PV = 3
        %}
        for i=1:1:nBus
            bus=BusType(i);    
            if (bus==2)%PQ bus
                fP(i)=-Pg(i)+Pd(i)-EVd(i);%EVs as gens. Return as much power as they charge with
                fQ(i)=-Qg(i)+Qd(i);
                for k=1:1:nBus
                    fP(i)=V(i)*V(k)*(G(i,k)*cos(Theta(i)-Theta(k))+B(i,k)*...
                        sin(Theta(i)-Theta(k)))+fP(i);
                    fQ(i)=V(i)*V(k)*(G(i,k)*sin(Theta(i)-Theta(k))-B(i,k)*...
                        cos(Theta(i)-Theta(k)))+fQ(i);
                end        
            elseif (bus==3)%PV bus
                fP(i)=-Pg(i)+Pd(i)-EVd(i);
                for k=1:1:nBus
                    fP(i)=V(i)*V(k)*(G(i,k)*cos(Theta(i)-Theta(k))+B(i,k)*...
                        sin(Theta(i)-Theta(k)))+fP(i);            
                end        
            end
        end
        
        end
        
        function [P,Q,V_copy]=FindPowerFlows(app,V,Theta,th_indices,v_indices,B,G,...
            nBus,x,rTh,rV)
        %{
            V: vector of partially-solved symbolic bus voltages
            Theta: vector of partially-solved symbolic bus angles (degrees)
            th_indices: vector of 0/1s indicating which thetas are variables in x
            v_indices: vector of 0/1s indicating which Vs are variables in x
            B: imaginary portion of Ybus
            G: real portion of Ybus
            nBus: number of busses in the system
            x: vector containing solution of initally unknown voltages and angles
            rTh: number of thetas in x    
        %}
        
        V_copy=V;Th_copy=Theta;
        P=zeros(nBus,nBus);
        Q=zeros(nBus,nBus);
        for i=1:1:rTh
            Th_copy(th_indices(i))=x(i);
        end
        Th_copy=double(Th_copy);
        
        for i=1:1:rV
            V_copy(v_indices(i))=x(i+rTh);
        end
        V_copy=double(V_copy);
        
        for j=1:1:nBus
            for k=1:1:nBus
                if (j~=k && (B(j,k)~=0 || G(j,k)~=0))
                    P(j,k)=V_copy(j,1)^2*(-G(j,k))+V_copy(j,1)*V_copy(k,1)*...
                        (G(j,k)*cos(Th_copy(j,1)-Th_copy(k,1))+B(j,k)*...
                        sin(Th_copy(j,1)-Th_copy(k,1)));
                    Q(j,k)=-(V_copy(j,1)^2)*(-B(j,k))+V_copy(j,1)*V_copy(k,1)*...
                        (G(j,k)*sin(Th_copy(j,1)-Th_copy(k,1))-B(j,k)*...
                        cos(Th_copy(j,1)-Th_copy(k,1)));
                end
            end
        end
        
        end
        
        function [P,Q,x,Vout,flag_out]=EvaluateCase(app,nBus,fP,fQ,Pg,Pd,EVd,Qg,Qd,V,Theta,B,...
            G,BusType,pMark,qMark,thMark,vMark,th_indices,v_indices,x_init,rV,...
            rTh,flagEV)
        %{
            flagEV: 1 if case includes EV loading, 0 otherwise
            fP: inital real power portions of vector f in symbolic representation
            fQ: inital rective power portions of vector f in symbolic representation
        %}
        
        if (flagEV)
            %Make power balance equations with EVs
            [fP,fQ]=MakePowerBalanceEquationEVs(app,nBus,fP,fQ,Pg,Pd,EVd,Qg,Qd,V,...
                Theta,B,G,BusType);
        else
            %Make power balance equations without EVs
            [fP,fQ]=MakePowerBalanceEquation(app,nBus,fP,fQ,Pg,Pd,EVd,Qg,Qd,V,Theta,B,G,...
                BusType);
        end
        
        %Create jacobian
        jacobian=MakeJacobian(app,nBus,pMark,qMark,thMark,vMark,fP,fQ,V,Theta);
        
        %Create f vector
        f=MakeF(app,nBus,pMark,qMark,fP,fQ);
        
        %Solve for x
        [x,flag_out]=FindX(app,jacobian,f,Theta,th_indices,V,v_indices,x_init,rV,rTh);
        
        %Solve for real and reactive power line flows
        [P,Q,Vout]=FindPowerFlows(app,V,Theta,th_indices,v_indices,B,G,nBus,x,rTh,rV);
        
        end
        
        function [AMWCOs,APCOs,SysAMWCO,TSI,SIv,SIp,maxMVA,maxMVACTG,nBranch,flag_out_mat]=RunCTGs(...
            app,nBus,fP,fQ,Pg,Pd,EVd,Qg,Qd,V,Theta,BusType,pMark,qMark,thMark,vMark,...
            th_indices,v_indices,x_init,rV,rTh,flagEV,Slim,Zmat,wv,Vsp,dV,nV,nP,...
            wp,Plim,flagSI)

        maxMVA=zeros(nBus,nBus);
        maxMVACTG=cell(nBus,nBus);
        APCOs=zeros(nBus,nBus);
        AMWCOs=zeros(nBus,nBus);
        SIv=zeros(nBus,nBus);
        SIp=zeros(nBus,nBus);
        flag_out_mat=zeros(nBus,nBus);
        
        [nBranch,~]=size(find(Zmat~=0));
        nBranch=nBranch/2;%since Zmat symmetrical
        
        for i=1:1:nBus
            for j=i+1:1:nBus%for each contingency
                if (abs(Zmat(i,j))~=0) 
                    Zmat_copy=Zmat;
                    Zmat_copy(i,j)=0;%one ctg, line failure from bus i to bus j
                    Zmat_copy(j,i)=0;
                    Ybus_copy=MakeYbus(app,Zmat_copy);
                    B=imag(Ybus_copy);G=real(Ybus_copy);
                    [P,Q,~,Vout,flag_out]=EvaluateCase(app,nBus,fP,fQ,Pg,Pd,EVd,Qg,Qd,V,...
                        Theta,B,G,BusType,pMark,qMark,thMark,vMark,th_indices,...
                        v_indices,x_init,rV,rTh,flagEV);
                    S=sqrt(P.^2+Q.^2);
                    if (flag_out)
                        flag_out_mat(i,j)=1;
                        flag_out_mat(j,i)=1;
                    end
                    for k=1:1:nBus
                        for m=1:1:nBus
                            if (k~=m)
                                limit=Slim(k,m);
                                currentMVA=S(k,m);
                                if (currentMVA>limit)
                                    APCOs(k,m)=(currentMVA/limit*100-100)+...
                                        APCOs(k,m);
                                end
                                if ((currentMVA/limit*100)>maxMVA(k,m))
                                    maxMVA(k,m)=(currentMVA/limit*100);
                                    maxMVACTG(k,m)={strcat('Outage Line: ',...
                                        num2str(i),'-',num2str(j))};
                                end
                            end
                        end
                    end 
                    if (flagSI)
                        for k=1:1:nBus
                            SIv(i,j)=SIv(i,j)+wv(k,1)/(2*nV)*...
                                ((abs(double(Vout(k,1)))-abs(Vsp(k,1)))/...
                                dV(k,1))^(2*nV);
                        end
                        for k=1:1:nBus
                            for m=1:1:nBus
                                if (k~=m)
                                    SIp(i,j)=SIp(i,j)+wp(k,m)/(2*nP)*...
                                        (P(k,m)/Plim(k,m))^(2*nP);
                                end
                            end
                        end
                    else
                        SIv=[];
                        SIp=[];
                    end
                end
            end
        end
        
        for i=1:1:nBus
            for j=1:1:nBus
                AMWCOs(i,j)=(APCOs(i,j)/100)*Slim(i,j);
            end
        end
        
        SysAMWCO=sum(AMWCOs,'all')/2;%averages ij and ji AMWCO contributions
        TSI=SysAMWCO/nBranch;
        
        end
        
        function [Vec,Cell_out]=MakePowerVec(app,Matrix,nBus,nBranch)
            
            Vec=zeros(1,nBranch);
            Cell_out=cell(1,nBranch);
            ctr=1;
            for i=1:1:nBus
                for j=i+1:1:nBus
                    Vec(ctr)=Matrix(i,j);
                    str=strcat(num2str(i),'-',num2str(j));
                    Cell_out(ctr)={str};
                    ctr=ctr+1;
                end
            end
            
        end
        
        function Program_Core(app,Excel_File,nV,nP)

        [Zmat,BusType,Knowns,Pg,Pd,Qg,Qd,EVd,Slim,wv,Vsp,dV,wp,Plim]=...
            ReadExcel(app,Excel_File);
        
        %{
        Zmat: Matrix of line impedances connecting busses. Diagonal entries are
            ignored. Zij is the impedance of the line connecting busses i and j.
            If no line connects busses i and j, set Zij to 0.
        BusType: Integer column vector indicating the type of each bus. The ith
            entry corresponds to the ith bus. Slack = 1, PQ = 2, PV = 3.
        Knowns: 2-D vector of known Theta, V, P, and Q in pu. Slack row = [Theta V], 
            PQ row = [P Q], PV row = [P V]. Must maintain this order.
        Pg: Column vector of real generation at each bus in pu. 0 if no generators
            at the bus.
        Pd: Column vector of real load demand at each bus in pu. 0 if no demand at
            the bus. Excludes EV loading.
        Qg: Column vector of reactive generation at each bus in pu. 0 if no
            generators at the bus.
        Qd: Column vector of reactive load demand at each bus in pu. 0 if no demand
            at the bus. Excludes EV loading.
        EVd: Column vector of EV load demand at each bus in pu (assumed purely
            real). Can be a matrix to analyze different EV loading simultaneously
        Slim: Matrix of complex line pu limits. Diagonal entries are 0 and Sij = 0
            if no line joins busses i and j.
        wv: Vector of heuristic weighting factors for bus voltage security index
            (VSI). Must have same number of entries as system busses.
        Vsp: Vector of bus voltage setpoints for bus VSI. Must have same number of
            entries as system busses.
        dV: Maximum permissible voltage deviation from setpoint. Used in VSI.
        nV: Positive integer used in VSI.
        wp: Matrix of heuristic weighting factors for line MW security index 
            (MWSI). Must be same size as Slim.
        nP: Positive integer used in MWSI.
        Plim: Matrix of MW line limits. Must be same size as Slim.
        
        Sbase is 100 MW.
        %}
        
        flagSI=1;%originally user-selectable, cut for time.    
            
        %Initalize matrices
        Ybus=MakeYbus(app,Zmat);
        
        B=imag(Ybus);
        G=real(Ybus);
        [nBus,~]=size(Ybus);
        syms Theta [nBus 1];
        syms V [nBus 1];
        syms fP [nBus 1];
        syms fQ [nBus 1];
        pMark=zeros(nBus,1);%equations in f
        qMark=zeros(nBus,1);
        thMark=zeros(nBus,1);%variables in x
        vMark=zeros(nBus,1);
        
        for i=1:1:nBus
            bus=BusType(i);
            if (bus==1)%Slack bus
                Theta(i)=Knowns(i,1);
                V(i)=Knowns(i,2);
            elseif (bus==2)%PQ bus
                thMark(i)=1;
                vMark(i)=1;
                pMark(i)=1;
                qMark(i)=1;
            else%PV bus
                V(i)=Knowns(i,2);
                thMark(i)=1;
                pMark(i)=1;
            end    
        end
        
        %Create inital x vector
        th_indices=find(thMark==1);
        [rTh,~]=size(th_indices);
        x_init=zeros(rTh,1);
        v_indices=find(vMark==1);
        [rV,~]=size(v_indices);
        x_init=[x_init;ones(rV,1)];
        
        %EV Base Case/Reference----------------------------------------------------
        
        flagEV=1;
        [P,Q,~,~,flag_out]=EvaluateCase(app,nBus,fP,fQ,Pg,Pd,EVd,Qg,Qd,V,Theta,B,G,BusType,...
            pMark,qMark,thMark,vMark,th_indices,v_indices,x_init,rV,rTh,flagEV);
        
        %Perform contingency analysis----------------------------------------------
        
        [AMWCOs,APCOs,SysAMWCO,TSI,SIv,SIp,maxMVA,maxMVACTG,nBranch,flag_out_mat]=RunCTGs(app,nBus,fP,...
            fQ,Pg,Pd,EVd,Qg,Qd,V,Theta,BusType,pMark,qMark,thMark,vMark,...
            th_indices,v_indices,x_init,rV,rTh,flagEV,Slim,Zmat,wv,Vsp,dV,nV,nP,wp,...
            Plim,flagSI);
        
        %Repeat above process for system without EV loading------------------------
        
        %No EV Base Case/Reference-------------------------------------------------
        
        flagEV=0;
        [Px,Qx,~,~,flag_outx]=EvaluateCase(app,nBus,fP,fQ,Pg,Pd,EVd,Qg,Qd,V,Theta,B,G,...
            BusType,pMark,qMark,thMark,vMark,th_indices,v_indices,x_init,rV,rTh,...
            flagEV);
        
        %Perform contingency analysis----------------------------------------------
        
        [AMWCOsx,APCOsx,SysAMWCOx,TSIx,SIvx,SIpx,maxMVAx,maxMVACTGx,~,flag_out_matx]=RunCTGs(...
            app,nBus,fP,fQ,Pg,Pd,EVd,Qg,Qd,V,Theta,BusType,pMark,qMark,thMark,vMark,...
            th_indices,v_indices,x_init,rV,rTh,flagEV,Slim,Zmat,wv,Vsp,dV,nV,nP,wp,...
            Plim,flagSI);
        
        %Compare EV and no EV cases------------------------------------------------
        
        comp_AMWCO_abs=AMWCOsx-AMWCOs;
        comp_APCO_abs=APCOsx-APCOs;
        comp_SIv_abs=SIvx-SIv;
        comp_SIp_abs=SIpx-SIp;
        comp_AMWCO_pct=cell(nBus);
        comp_APCO_pct=cell(nBus);
        comp_SIv_pct=cell(nBus);
        comp_SIp_pct=cell(nBus);
        comp_maxMVA_pct=cell(nBus);
        for i=1:1:nBus
            for j=1:1:nBus
                if (i~=j)
                    if (AMWCOs(i,j)~=0)
                        comp_AMWCO_pct(i,j)={abs(AMWCOsx(i,j)-AMWCOs(i,j))./...
                            abs(AMWCOs(i,j))};
                    else
                        comp_AMWCO_pct(i,j)={'N/A'};
                    end
                    
                    if (APCOs(i,j)~=0)
                        comp_APCO_pct(i,j)={abs(APCOsx(i,j)-APCOs(i,j))./...
                            abs(APCOs(i,j))};
                    else
                        comp_APCO_pct(i,j)={'N/A'};
                    end
                    
                    if (SIv(i,j)~=0)
                        comp_SIv_pct(i,j)={abs(SIvx(i,j)-SIv(i,j))./abs(SIv(i,j))};
                    else
                        comp_SIv_pct(i,j)={'N/A'};
                    end
                    
                    if (SIp(i,j)~=0)
                        comp_SIp_pct(i,j)={abs(SIpx(i,j)-SIp(i,j))./abs(SIp(i,j))};
                    else
                        comp_SIp_pct(i,j)={'N/A'};
                    end
                    if (maxMVA(i,j)~=0)
                        comp_maxMVA_pct(i,j)={abs(maxMVAx(i,j)-maxMVA(i,j))./abs(maxMVA(i,j))};
                    else
                        comp_maxMVA_pct(i,j)={'N/A'};
                    end
                end
            end
        end
        comp_SysAMWCO_abs=SysAMWCOx-SysAMWCO;
        comp_SysAMWCO_pct=abs(SysAMWCOx-SysAMWCO)./abs(SysAMWCO);
        comp_TSI_abs=TSIx-TSI;
        comp_TSI_pct=abs(TSIx-TSI)./abs(TSI);
        comp_maxMVA_abs=maxMVAx-maxMVA;                
               
        %No EV Results--------------------------------------
        
        %Real Power (P)
        app.PUITable.RowName='numbered';
        app.PUITable.ColumnName='numbered';        
        app.PUITable.Data=Px;
        
        %Real Power (Q)
        app.QUITable.RowName='numbered';
        app.QUITable.ColumnName='numbered';        
        app.QUITable.Data=Qx;
        
        %AMWCO
        app.AMWCOUITable.RowName='numbered';
        app.AMWCOUITable.ColumnName='numbered';        
        app.AMWCOUITable.Data=AMWCOsx;
        
        %APCO
        app.APCOUITable.RowName='numbered';
        app.APCOUITable.ColumnName='numbered';        
        app.APCOUITable.Data=APCOsx;
        
        %SIv
        app.SIvUITable.RowName='numbered';
        app.SIvUITable.ColumnName='numbered';        
        app.SIvUITable.Data=SIvx;
        
        %SIp
        app.SIpUITable.RowName='numbered';
        app.SIpUITable.ColumnName='numbered';        
        app.SIpUITable.Data=SIpx;
        
        %Others
        app.SysAMWCOEditField.Value=SysAMWCOx;
        app.TSIEditField.Value=TSIx;
        
        app.MaxMVAUITable.RowName='numbered';
        app.MaxMVAUITable.ColumnName='numbered';
        app.MaxMVAUITable.Data=maxMVAx;
        
        app.MaxMVACTGUITable.RowName='numbered';
        app.MaxMVACTGUITable.ColumnName='numbered';
        app.MaxMVACTGUITable.Data=maxMVACTGx;
        
        %EV Results-----------------------------------------
        
        %Real Power (P)
        app.PUITableEV.RowName='numbered';
        app.PUITableEV.ColumnName='numbered';        
        app.PUITableEV.Data=P;
        
        %Real Power (Q)
        app.QUITableEV.RowName='numbered';
        app.QUITableEV.ColumnName='numbered';        
        app.QUITableEV.Data=Q;
        
        %AMWCO
        app.AMWCOUITableEV.RowName='numbered';
        app.AMWCOUITableEV.ColumnName='numbered';        
        app.AMWCOUITableEV.Data=AMWCOs;
        
        %APCO
        app.APCOUITableEV.RowName='numbered';
        app.APCOUITableEV.ColumnName='numbered';        
        app.APCOUITableEV.Data=APCOs;
        
        %SIv
        app.SIvUITableEV.RowName='numbered';
        app.SIvUITableEV.ColumnName='numbered';        
        app.SIvUITableEV.Data=SIv;
        
        %SIp
        app.SIpUITableEV.RowName='numbered';
        app.SIpUITableEV.ColumnName='numbered';        
        app.SIpUITableEV.Data=SIp;
        
        %Others
        app.SysAMWCOEditFieldEV.Value=SysAMWCO;
        app.TSIEditFieldEV.Value=TSI;
        
        app.MaxMVAUITableEV.RowName='numbered';
        app.MaxMVAUITableEV.ColumnName='numbered';
        app.MaxMVAUITableEV.Data=maxMVA;
        
        app.MaxMVACTGUITableEV.RowName='numbered';
        app.MaxMVACTGUITableEV.ColumnName='numbered';
        app.MaxMVACTGUITableEV.Data=maxMVACTG;
        
        
        %Comparison Results---------------------------------
        
        %Absolute Differences---------------------
        
        %AMWCO
        app.AMWCOUITableABS.RowName='numbered';
        app.AMWCOUITableABS.ColumnName='numbered';        
        app.AMWCOUITableABS.Data=comp_AMWCO_abs;
        
        %APCO
        app.APCOUITableABS.RowName='numbered';
        app.APCOUITableABS.ColumnName='numbered';        
        app.APCOUITableABS.Data=comp_APCO_abs;
        
        %SIv
        app.SIvUITableABS.RowName='numbered';
        app.SIvUITableABS.ColumnName='numbered';        
        app.SIvUITableABS.Data=comp_SIv_abs;
        
        %SIp
        app.SIpUITableABS.RowName='numbered';
        app.SIpUITableABS.ColumnName='numbered';        
        app.SIpUITableABS.Data=comp_SIp_abs;
        
        %Others
        app.SysAMWCOEditFieldABS.Value=comp_SysAMWCO_abs;
        app.TSIEditFieldABS.Value=comp_TSI_abs;
        
        app.MaxMVAUITableABS.RowName='numbered';
        app.MaxMVAUITableABS.ColumnName='numbered';
        app.MaxMVAUITableABS.Data=comp_maxMVA_abs;
        
        %Percent Differences----------------------
        
        %AMWCO
        app.AMWCOUITablePCT.RowName='numbered';
        app.AMWCOUITablePCT.ColumnName='numbered';        
        app.AMWCOUITablePCT.Data=comp_AMWCO_pct;
        
        %APCO
        app.APCOUITablePCT.RowName='numbered';
        app.APCOUITablePCT.ColumnName='numbered';        
        app.APCOUITablePCT.Data=comp_APCO_pct;
        
        %SIv
        app.SIvUITablePCT.RowName='numbered';
        app.SIvUITablePCT.ColumnName='numbered';        
        app.SIvUITablePCT.Data=comp_SIv_pct;
        
        %SIp
        app.SIpUITablePCT.RowName='numbered';
        app.SIpUITablePCT.ColumnName='numbered';        
        app.SIpUITablePCT.Data=comp_SIp_pct;
        
        %Others
        app.SysAMWCOEditFieldPCT.Value=comp_SysAMWCO_pct;
        app.TSIEditFieldPCT.Value=comp_TSI_pct;
        
        app.MaxMVAUITablePCT.RowName='numbered';
        app.MaxMVAUITablePCT.ColumnName='numbered';
        app.MaxMVAUITablePCT.Data=comp_maxMVA_pct;
        
        %Pie Charts---------------------------------
        
        dim=ceil(sqrt(nBranch));
        
        [Slim_vec,lines]=MakePowerVec(app,Slim,nBus,nBranch); 
        
        Sx=sqrt(Px.^2+Qx.^2);
        [Sx_vec,~]=MakePowerVec(app,Sx,nBus,nBranch); 
        Sx_vec=Sx_vec./Slim_vec;
        
        S=sqrt(P.^2+Q.^2);
        [S_vec,~]=MakePowerVec(app,S,nBus,nBranch);
        S_vec=S_vec./Slim_vec;
        
        h=tiledlayout(app.NoEVsTabPie,dim,dim);
        for i=1:1:nBranch
            ax=nexttile(h);
            entry=Sx_vec(i);
            if (entry<1)
                pie(ax,[entry,1-entry],{'Loading','Spare'});
                colormap(ax,[1 0 0; ... %red
                    0.5 0.5 0.5]);%grey                
            else
                pie(ax,1,{'Loading'});
                colormap(ax,[1 0 0]);%red                
            end  
            str=strcat('Line ',lines{i},': ',num2str(entry*100),'%');
            title(ax,str);
        end
        
        h=tiledlayout(app.EVsTabPie,dim,dim);
        for i=1:1:nBranch
            ax=nexttile(h);
            entry=S_vec(i);
            if (entry<1)
                pie(ax,[entry,1-entry],{'Loading','Spare'});
                colormap(ax,[1 0 0; ... %red
                    0.5 0.5 0.5]);%grey
            else
                pie(ax,1,{'Loading'});
                colormap(ax,[1 0 0]);%red
            end
            str=strcat('Line ',lines{i},': ',num2str(entry*100),'%');
            title(ax,str);
        end
        
        %Bar Graphs---------------------------------        
           
        [Px_vec,~]=MakePowerVec(app,Px,nBus,nBranch);                
        [Qx_vec,~]=MakePowerVec(app,Qx,nBus,nBranch);
                
        [P_vec,~]=MakePowerVec(app,P,nBus,nBranch);                 
        [Q_vec,~]=MakePowerVec(app,Q,nBus,nBranch);
        
        y=[];
        for i=1:1:nBranch            
            y=[y;abs(Px_vec(i)),abs(Qx_vec(i)),abs(Sx_vec(i))];            
        end
        yaxis_top=0.5+max(y,[],'all');
       
        h=bar(app.UIAxesNoEVs,y);
        xticklabels(app.UIAxesNoEVs,lines);
        axis(app.UIAxesNoEVs,[0.5 nBranch+0.5 0 yaxis_top]);
        title(app.UIAxesNoEVs,'Line Loading without EVs');
        xlabel(app.UIAxesNoEVs,'Line');
        ylabel(app.UIAxesNoEVs,'pu');
        set(h,{'DisplayName'},{'P','Q','S'}');
        legend(app.UIAxesNoEVs);        
        
        y=[];
        for i=1:1:nBranch            
            y=[y;abs(P_vec(i)),abs(Q_vec(i)),abs(S_vec(i))];            
        end
        
        h=bar(app.UIAxesEVs,y);
        xticklabels(app.UIAxesEVs,lines);
        axis(app.UIAxesEVs,[0.5 nBranch+0.5 0 yaxis_top]);
        title(app.UIAxesEVs,'Line Loading with EVs');
        xlabel(app.UIAxesEVs,'Line');
        ylabel(app.UIAxesEVs,'pu');
        set(h,{'DisplayName'},{'P','Q','S'}');
        legend(app.UIAxesEVs);
        
                   
        end         
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SelectExcelFileButton
        function SelectExcelFileButtonPushed(app, event)
            [file,path]=uigetfile('*.xlsx');
            app.UIFigure.Visible='on';
            app.FILE=file;
            app.PATH=path;
            app.ExcelFilewithInputDataEditField.Value=string(file);
        end

        % Button pushed function: AnalyzeCaseButton
        function AnalyzeCaseButtonPushed(app, event)
            
            clc;
            
            app.ProgramStatusLamp.Color='green';pause(0.1);
            
            app.PUITable.clo;app.QUITable.clo;
            app.AMWCOUITable.clo;app.APCOUITable.clo;
            app.SIvUITable.clo;app.SIpUITable.clo;
            
            app.PUITableEV.clo;app.QUITableEV.clo;
            app.AMWCOUITableEV.clo;app.APCOUITableEV.clo;
            app.SIvUITableEV.clo;app.SIpUITableEV.clo;
            
            app.AMWCOUITableABS.clo;app.APCOUITableABS.clo;
            app.SIvUITableABS.clo;app.SIpUITableABS.clo;
            
            app.AMWCOUITablePCT.clo;app.APCOUITablePCT.clo;
            app.SIvUITablePCT.clo;app.SIpUITablePCT.clo;
            
            app.SysAMWCOEditField.clo;app.TSIEditField.clo;
            app.MaxMVAUITable.clo;
            app.MaxMVACTGUITable.clo;
            
            app.SysAMWCOEditFieldEV.clo;app.TSIEditFieldEV.clo;
            app.MaxMVAUITableEV.clo;
            app.MaxMVACTGUITableEV.clo;
            
            app.SysAMWCOEditFieldABS.clo;app.TSIEditFieldABS.clo;
            app.MaxMVAUITableABS.clo;
            
            app.SysAMWCOEditFieldPCT.clo;app.TSIEditFieldPCT.clo;
            app.MaxMVAUITablePCT.clo;
            
            app.UIAxesEVs.cla;app.UIAxesNoEVs.cla;
            
            nV=app.nVEditField.Value;
            nP=app.nPEditField.Value;
            
            temp1=string(app.PATH);
            temp2=string(app.FILE);
            Excel_File=strcat(temp1,temp2);
            
            Program_Core(app,Excel_File,nV,nP);
            
            app.ProgramStatusLamp.Color='black';pause(0.1);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.InputsTab = uitab(app.TabGroup);
            app.InputsTab.Title = 'Inputs';
            app.SelectExcelFileButton = uibutton(app.InputsTab, 'push');
            app.SelectExcelFileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectExcelFileButtonPushed, true);
            app.SelectExcelFileButton.Text = 'Select Excel File';
            app.ExcelFilewithInputDataEditField = uieditfield(app.InputsTab, 'text');
            app.nVEditFieldLabel = uilabel(app.InputsTab);
            app.nVEditFieldLabel.Text = 'nV';
            app.nVEditField = uieditfield(app.InputsTab, 'numeric');
            app.nVEditField.Limits = [1 Inf];
            app.nVEditField.Value = 1;
            app.nPEditFieldLabel = uilabel(app.InputsTab);
            app.nPEditFieldLabel.Text = 'nP';
            app.nPEditField = uieditfield(app.InputsTab, 'numeric');
            app.nPEditField.Limits = [1 Inf];
            app.nPEditField.Value = 1;
            app.ProgramStatusLampLabel = uilabel(app.InputsTab);
            app.ProgramStatusLampLabel.Text = 'Program Status';
            app.ProgramStatusLamp = uilamp(app.InputsTab);
            app.AnalyzeCaseButton = uibutton(app.InputsTab, 'push');
            app.AnalyzeCaseButton.ButtonPushedFcn = createCallbackFcn(app, @AnalyzeCaseButtonPushed, true);
            app.AnalyzeCaseButton.Text = 'Analyze Case';
            app.NoEVResultsTab = uitab(app.TabGroup);
            app.NoEVResultsTab.Title = 'No EV Results';
            app.SecurityParameters = uitabgroup(app.NoEVResultsTab);
            app.RealPowerTab = uitab(app.SecurityParameters);
            app.RealPowerTab.Title = 'Real Power';
            app.PUITable = uitable(app.RealPowerTab);
            app.PUITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.PUITable.RowName = {};
            app.ReactivePowerTab = uitab(app.SecurityParameters);
            app.ReactivePowerTab.Title = 'Reactive Power';
            app.QUITable = uitable(app.ReactivePowerTab);
            app.QUITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.QUITable.RowName = {};
            app.AMWCOTab = uitab(app.SecurityParameters);
            app.AMWCOTab.Title = 'AMWCO';
            app.AMWCOUITable = uitable(app.AMWCOTab);
            app.AMWCOUITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.AMWCOUITable.RowName = {};
            app.APCOTab = uitab(app.SecurityParameters);
            app.APCOTab.Title = 'APCO';
            app.APCOUITable = uitable(app.APCOTab);
            app.APCOUITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.APCOUITable.RowName = {};
            app.SIvTab = uitab(app.SecurityParameters);
            app.SIvTab.Title = 'SIv';
            app.SIvUITable = uitable(app.SIvTab);
            app.SIvUITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.SIvUITable.RowName = {};
            app.SIpTab = uitab(app.SecurityParameters);
            app.SIpTab.Title = 'SIp';
            app.SIpUITable = uitable(app.SIpTab);
            app.SIpUITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.SIpUITable.RowName = {};
            app.OthersTab = uitab(app.SecurityParameters);
            app.OthersTab.Title = 'Others';
            app.SysAMWCOEditFieldLabel = uilabel(app.OthersTab);
            app.SysAMWCOEditFieldLabel.Text = 'SysAMWCO';
            app.SysAMWCOEditField = uieditfield(app.OthersTab, 'numeric');
            app.TSIEditFieldLabel = uilabel(app.OthersTab);
            app.TSIEditFieldLabel.Text = 'TSI';
            app.TSIEditField = uieditfield(app.OthersTab, 'numeric');
            app.MaxMVAUITable = uitable(app.OthersTab);
            app.MaxMVAUITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.MaxMVAUITable.RowName = {};
            app.MaxMVACTGUITable = uitable(app.OthersTab);
            app.MaxMVACTGUITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.MaxMVACTGUITable.RowName = {};
            app.EditFieldLabel = uieditfield(app.OthersTab, 'text');
            app.EditFieldLabel.Editable = 'off';
            app.EditFieldLabel.Value = 'Max Line MVA as % of Limit';
            app.EditFieldLabel_2 = uieditfield(app.OthersTab, 'text');
            app.EditFieldLabel_2.Editable = 'off';
            app.EditFieldLabel_2.Value = 'CTG for Max Line MVA as % of Limit';
            app.EVResultsTab = uitab(app.TabGroup);
            app.EVResultsTab.Title = 'EV Results';
            app.SecurityParametersEV = uitabgroup(app.EVResultsTab);
            app.RealPowerTabEV = uitab(app.SecurityParametersEV);
            app.RealPowerTabEV.Title = 'Real Power';
            app.PUITableEV = uitable(app.RealPowerTabEV);
            app.PUITableEV.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.PUITableEV.RowName = {};
            app.ReactivePowerTabEV = uitab(app.SecurityParametersEV);
            app.ReactivePowerTabEV.Title = 'Reactive Power';
            app.QUITableEV = uitable(app.ReactivePowerTabEV);
            app.QUITableEV.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.QUITableEV.RowName = {};
            app.AMWCOTabEV = uitab(app.SecurityParametersEV);
            app.AMWCOTabEV.Title = 'AMWCO';
            app.AMWCOUITableEV = uitable(app.AMWCOTabEV);
            app.AMWCOUITableEV.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.AMWCOUITableEV.RowName = {};
            app.APCOTabEV = uitab(app.SecurityParametersEV);
            app.APCOTabEV.Title = 'APCO';
            app.APCOUITableEV = uitable(app.APCOTabEV);
            app.APCOUITableEV.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.APCOUITableEV.RowName = {};
            app.SIvTabEV = uitab(app.SecurityParametersEV);
            app.SIvTabEV.Title = 'SIv';
            app.SIvUITableEV = uitable(app.SIvTabEV);
            app.SIvUITableEV.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.SIvUITableEV.RowName = {};
            app.SIpTabEV = uitab(app.SecurityParametersEV);
            app.SIpTabEV.Title = 'SIp';
            app.SIpUITableEV = uitable(app.SIpTabEV);
            app.SIpUITableEV.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.SIpUITableEV.RowName = {};
            app.SingleParametersTabEV = uitab(app.SecurityParametersEV);
            app.SingleParametersTabEV.Title = 'Others';
            app.SysAMWCOEditField_2Label = uilabel(app.SingleParametersTabEV);
            app.SysAMWCOEditField_2Label.Text = 'SysAMWCO';
            app.SysAMWCOEditFieldEV = uieditfield(app.SingleParametersTabEV, 'numeric');
            app.TSIEditField_2Label = uilabel(app.SingleParametersTabEV);
            app.TSIEditField_2Label.Text = 'TSI';
            app.TSIEditFieldEV = uieditfield(app.SingleParametersTabEV, 'numeric');
            app.MaxMVAUITableEV = uitable(app.SingleParametersTabEV);
            app.MaxMVAUITableEV.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.MaxMVAUITableEV.RowName = {};
            app.MaxMVACTGUITableEV = uitable(app.SingleParametersTabEV);
            app.MaxMVACTGUITableEV.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.MaxMVACTGUITableEV.RowName = {};
            app.EditFieldLabel_3 = uieditfield(app.SingleParametersTabEV, 'text');
            app.EditFieldLabel_3.Editable = 'off';
            app.EditFieldLabel_3.Value = 'Max Line MVA as % of Limit';
            app.EditFieldLabel_4 = uieditfield(app.SingleParametersTabEV, 'text');
            app.EditFieldLabel_4.Editable = 'off';
            app.EditFieldLabel_4.Value = 'CTG for Max Line MVA as % of Limit';
            app.ComparisonsTab = uitab(app.TabGroup);
            app.ComparisonsTab.Title = 'Comparisons';
            app.SecurityParametersCOMP = uitabgroup(app.ComparisonsTab);
            app.AMWCOTabABS = uitab(app.SecurityParametersCOMP);
            app.AMWCOTabABS.Title = 'AMWCO';
            app.AMWCOUITableABS = uitable(app.AMWCOTabABS);
            app.AMWCOUITableABS.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.AMWCOUITableABS.RowName = {};
            app.APCOTabABS = uitab(app.SecurityParametersCOMP);
            app.APCOTabABS.Title = 'APCO';
            app.APCOUITableABS = uitable(app.APCOTabABS);
            app.APCOUITableABS.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.APCOUITableABS.RowName = {};
            app.SIvTabABS = uitab(app.SecurityParametersCOMP);
            app.SIvTabABS.Title = 'SIv';
            app.SIvUITableABS = uitable(app.SIvTabABS);
            app.SIvUITableABS.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.SIvUITableABS.RowName = {};
            app.SIpTabABS = uitab(app.SecurityParametersCOMP);
            app.SIpTabABS.Title = 'SIp';
            app.SIpUITableABS = uitable(app.SIpTabABS);
            app.SIpUITableABS.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.SIpUITableABS.RowName = {};
            app.SingleParametersTabCOMP = uitab(app.SecurityParametersCOMP);
            app.SingleParametersTabCOMP.Title = 'Others';
            app.TSIEditField_2Label_2 = uilabel(app.SingleParametersTabCOMP);
            app.TSIEditField_2Label_2.Text = 'TSI';
            app.TSIEditFieldABS = uieditfield(app.SingleParametersTabCOMP, 'numeric');
            app.SysAMWCOLabel = uilabel(app.SingleParametersTabCOMP);
            app.SysAMWCOLabel.Text = 'SysAMWCO %';
            app.SysAMWCOEditFieldPCT = uieditfield(app.SingleParametersTabCOMP, 'numeric');
            app.TSILabel = uilabel(app.SingleParametersTabCOMP);
            app.TSILabel.Text = 'TSI %';
            app.TSIEditFieldPCT = uieditfield(app.SingleParametersTabCOMP, 'numeric');
            app.MaxMVAUITableABS = uitable(app.SingleParametersTabCOMP);
            app.MaxMVAUITableABS.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.MaxMVAUITableABS.RowName = {};
            app.MaxMVAUITablePCT = uitable(app.SingleParametersTabCOMP);
            app.MaxMVAUITablePCT.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.MaxMVAUITablePCT.RowName = {};
            app.SysAMWCOEditField_2Label_2 = uilabel(app.SingleParametersTabCOMP);
            app.SysAMWCOEditField_2Label_2.Text = 'SysAMWCO';
            app.SysAMWCOEditFieldABS = uieditfield(app.SingleParametersTabCOMP, 'numeric');
            app.EditFieldLabel_5 = uieditfield(app.SingleParametersTabCOMP, 'text');
            app.EditFieldLabel_5.Editable = 'off';
            app.EditFieldLabel_5.Value = 'Max Line MVA as % of Limit';
            app.EditFieldLabel_6 = uieditfield(app.SingleParametersTabCOMP, 'text');
            app.EditFieldLabel_6.Editable = 'off';
            app.EditFieldLabel_6.Value = 'Max Line MVA as % of Limit %';
            app.AMWCOTabPCT = uitab(app.SecurityParametersCOMP);
            app.AMWCOTabPCT.Title = 'AMWCO %';
            app.AMWCOUITablePCT = uitable(app.AMWCOTabPCT);
            app.AMWCOUITablePCT.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.AMWCOUITablePCT.RowName = {};
            app.APCOTabPCT = uitab(app.SecurityParametersCOMP);
            app.APCOTabPCT.Title = 'APCO %';
            app.APCOUITablePCT = uitable(app.APCOTabPCT);
            app.APCOUITablePCT.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.APCOUITablePCT.RowName = {};
            app.SIVTabPCT = uitab(app.SecurityParametersCOMP);
            app.SIVTabPCT.Title = 'SIv%';
            app.SIvUITablePCT = uitable(app.SIVTabPCT);
            app.SIvUITablePCT.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.SIvUITablePCT.RowName = {};
            app.SIpTab_2 = uitab(app.SecurityParametersCOMP);
            app.SIpTab_2.Title = 'SIp %';
            app.SIpUITablePCT = uitable(app.SIpTab_2);
            app.SIpUITablePCT.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.SIpUITablePCT.RowName = {};
            app.LinePieChartsTab = uitab(app.TabGroup);
            app.LinePieChartsTab.Title = 'Line Pie Charts';
            app.PieChartTabGroup = uitabgroup(app.LinePieChartsTab);
            app.NoEVsTabPie = uitab(app.PieChartTabGroup);
            app.NoEVsTabPie.Title = 'No EVs';
            app.EVsTabPie = uitab(app.PieChartTabGroup);
            app.EVsTabPie.Title = 'EVs';
            app.LineBarGraphsTab = uitab(app.TabGroup);
            app.LineBarGraphsTab.Title = 'Line Bar Graphs';
            app.BarGraphTabGroup = uitabgroup(app.LineBarGraphsTab);
            app.NoEVsTabBG = uitab(app.BarGraphTabGroup);
            app.NoEVsTabBG.Title = 'No EVs';
            app.UIAxesNoEVs = uiaxes(app.NoEVsTabBG);
            title(app.UIAxesNoEVs, 'Title')
            xlabel(app.UIAxesNoEVs, 'X')
            ylabel(app.UIAxesNoEVs, 'Y')
            app.EVsTabBG = uitab(app.BarGraphTabGroup);
            app.EVsTabBG.Title = 'EVs';
            app.UIAxesEVs = uiaxes(app.EVsTabBG);
            title(app.UIAxesEVs, 'Title')
            xlabel(app.UIAxesEVs, 'X')
            ylabel(app.UIAxesEVs, 'Y')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = UserInterface

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end