classdef UserInterfacePresetSystemRevised < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        MainTab                         matlab.ui.container.Tab
        ProgramRunningLampLabel         matlab.ui.control.Label
        ProgramRunningLamp              matlab.ui.control.Lamp
        RunButton                       matlab.ui.control.Button
        EVLoadinginpuatEachBusPanel     matlab.ui.container.Panel
        Bus2EditFieldLabel              matlab.ui.control.Label
        Bus2EditField                   matlab.ui.control.NumericEditField
        Bus3EditFieldLabel              matlab.ui.control.Label
        Bus3EditField                   matlab.ui.control.NumericEditField
        Bus4EditFieldLabel              matlab.ui.control.Label
        Bus4EditField                   matlab.ui.control.NumericEditField
        SIvParametersPanel              matlab.ui.container.Panel
        wforV2EditFieldLabel            matlab.ui.control.Label
        wforV2EditField                 matlab.ui.control.NumericEditField
        wforV3EditFieldLabel            matlab.ui.control.Label
        wforV3EditField                 matlab.ui.control.NumericEditField
        deltaforV2EditFieldLabel        matlab.ui.control.Label
        deltaforV2EditField             matlab.ui.control.NumericEditField
        deltaforV3EditFieldLabel        matlab.ui.control.Label
        deltaforV3EditField             matlab.ui.control.NumericEditField
        SIpwParametersforEachLinePanel  matlab.ui.container.Panel
        Label                           matlab.ui.control.Label
        EditField12                     matlab.ui.control.NumericEditField
        Label_2                         matlab.ui.control.Label
        EditField13                     matlab.ui.control.NumericEditField
        Label_3                         matlab.ui.control.Label
        EditField23                     matlab.ui.control.NumericEditField
        Label_4                         matlab.ui.control.Label
        EditField24                     matlab.ui.control.NumericEditField
        Label_5                         matlab.ui.control.Label
        EditField34                     matlab.ui.control.NumericEditField
        NoSolutionLampLabel             matlab.ui.control.Label
        NoSolutionLamp                  matlab.ui.control.Lamp
        BaseResultsTab                  matlab.ui.container.Tab
        UITable_CtgLineParams           matlab.ui.control.Table
        SysAMWCOEditFieldLabel          matlab.ui.control.Label
        SysAMWCOEditField               matlab.ui.control.NumericEditField
        TSIEditFieldLabel               matlab.ui.control.Label
        TSIEditField                    matlab.ui.control.NumericEditField
        UITable_PwrBase                 matlab.ui.control.Table
        AsCtgResourceResultsTab         matlab.ui.container.Tab
        UITable_PwrCr                   matlab.ui.control.Table
        UITable_CtgLineParamsCr         matlab.ui.control.Table
        SysAMWCOEditField_2Label        matlab.ui.control.Label
        SysAMWCOEditFieldCr             matlab.ui.control.NumericEditField
        TSIEditField_3Label             matlab.ui.control.Label
        TSIEditFieldCr                  matlab.ui.control.NumericEditField
        ComparisonsTab                  matlab.ui.container.Tab
        UITable_CompCtgLineParamsPct    matlab.ui.control.Table
        UITable_CompCtgLineParamsAbs    matlab.ui.control.Table
        SysAMWCOEditField_2Label_2      matlab.ui.control.Label
        SysAMWCOEditFieldAbs            matlab.ui.control.NumericEditField
        SysAMWCOLabel                   matlab.ui.control.Label
        SysAMWCOEditFieldPct            matlab.ui.control.NumericEditField
        TSILabel                        matlab.ui.control.Label
        TSIEditFieldAbs                 matlab.ui.control.NumericEditField
        TSILabel_2                      matlab.ui.control.Label
        TSIEditFieldPct                 matlab.ui.control.NumericEditField
        EditField                       matlab.ui.control.EditField
        EditField_2                     matlab.ui.control.EditField
        PieChartsTab                    matlab.ui.container.Tab
        TabGroup2                       matlab.ui.container.TabGroup
        BaseTabPie                      matlab.ui.container.Tab
        AsCtgResourceTabPie             matlab.ui.container.Tab
        BarGraphsTab                    matlab.ui.container.Tab
        TabGroup3                       matlab.ui.container.TabGroup
        BaseTabBar                      matlab.ui.container.Tab
        UIAxesBase                      matlab.ui.control.UIAxes
        AsCtgResourceTabBar             matlab.ui.container.Tab
        UIAxesCr                        matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        
        function Main(app,EVloading,wV2,wV3,dV2,dV3,wP)
            %{EVloading: Vector of EV load at each bus
            %}
            
            j=sqrt(-1);
            Zmat=[0 0.02+0.24*j 0.06+0.08*j 0;
                0.02+0.24*j 0 0.02+0.12*j 0.04+0.06*j;
                0.06+0.08*j 0.02+0.12*j 0 0.04+0.24*j;
                0 0.04+0.06*j 0.04+0.24*j 0];
            Ybus=MakeYbus(app,Zmat);
            G=real(Ybus);B=imag(Ybus);
            flat_start=[0 0 0 1 1];%[Th2 Th3 Th4 V2 V3]
            Pd=[0;1.1;2;0];Qd=[0;0.65;0.5;0];
            %-1: There is a generator but it does not matter for
            %the bus type
            Pg=[-1;0;0;2];Qg=[-1;0;0;-1];
            Plim=[0.8;2;1.2;2;2];
            
            %Base Case for EVs as Demand
            [x,flag]=FindX(app,flat_start,1e-3,G,B,Pd,Qd,Pg,Qg,...
                EVloading);
            
            if (flag)
                return
            end
            
            [P,Q,S,Pr,Qr,Sr,Sm]=FindPower(app,x,Zmat);
            
            %Base Case for EVs as Contengency Resource
            [x_cr,flag]=FindX(app,flat_start,1e-3,G,B,Pd,Qd,Pg,Qg,...
                -1.*EVloading);
            
            if (flag)
                return
            end
            
            [P_cr,Q_cr,S_cr,Pr_cr,Qr_cr,Sr_cr,Sm_cr]=FindPower(app,x_cr,Zmat);
            
            %n-1 Contengencies  for EVs as Demand
            [AMWCOs,APCOs,SysAMWCO,TSI,SIv,SIp,MaxMVA_pct,...
                MaxMVA_ctg,flag]=RunContengencies(app,Zmat,flat_start,...
                Plim,wV2,wV3,dV2,dV3,wP,EVloading,Pd,Qd,Pg,Qg);
            
            if (flag)
                return
            end
            
            %n-1 Contengencies  for EVs as Contengengy Resource
            [AMWCOs_cr,APCOs_cr,SysAMWCO_cr,TSI_cr,SIv_cr,SIp_cr,...
                MaxMVA_pct_cr,MaxMVA_ctg_cr,flag]=RunContengencies(...
                app,Zmat,flat_start,Plim,wV2,wV3,dV2,dV3,wP,...
                -1.*EVloading,Pd,Qd,Pg,Qg);
            
            if (flag)
                return
            end
            
            %Comparisons Between EVs as Demand Only and as a 
            %Contengency Resource
            
            [comp_AMWCO_abs,comp_AMWCO_pct]=MakeComparisons(app,AMWCOs,...
                AMWCOs_cr);
            [comp_APCO_abs,comp_APCO_pct]=MakeComparisons(app,APCOs,...
                APCOs_cr);
            [comp_SIv_abs,comp_SIv_pct]=MakeComparisons(app,SIv,...
                SIv_cr);
            [comp_SIp_abs,comp_SIp_pct]=MakeComparisons(app,SIp,...
                SIp_cr);
            [comp_SysAMWCO_abs,comp_SysAMWCO_pct]=MakeComparisons(app,...
                SysAMWCO,SysAMWCO_cr);
            [comp_TSI_abs,comp_TSI_pct]=MakeComparisons(app,TSI,...
                TSI_cr);
            [comp_MaxMVA_abs,comp_MaxMVA_pct]=MakeComparisons(app,...
                MaxMVA_pct,MaxMVA_pct_cr);
            
            %Base Results----------------------------------------
            
            app.UITable_PwrBase.Data=[P,Q,S,Pr,Qr,Sr];
            app.UITable_PwrBase.RowName={'1-2','1-3','2-3','2-4',...
                '3-4'};            
                      
            data_cell=cell(5,5);
            
            for i=1:1:5
                
                data_cell(i,1)={AMWCOs(i)};
                data_cell(i,2)={APCOs(i)};
                data_cell(i,3)={MaxMVA_pct(i)};
                data_cell(i,4)=MaxMVA_ctg(i);
                data_cell(i,5)={SIp(i)};
                data_cell(i,6)={SIv(i)};
                
            end
            
            app.UITable_CtgLineParams.Data=data_cell;
            app.UITable_CtgLineParams.RowName={'1-2','1-3','2-3',...
                '2-4','3-4'};
            
            app.SysAMWCOEditField.Value=SysAMWCO;
            app.TSIEditField.Value=TSI;
            
            %Cr Results----------------------------------------
            
            app.UITable_PwrCr.Data=[P_cr,Q_cr,S_cr,Pr_cr,Qr_cr,Sr_cr];
            app.UITable_PwrCr.RowName={'1-2','1-3','2-3','2-4',...
                '3-4'};          
            
            data_cell=cell(5,5);
            
            for i=1:1:5
                
                data_cell(i,1)={AMWCOs_cr(i)};
                data_cell(i,2)={APCOs_cr(i)};
                data_cell(i,3)={MaxMVA_pct_cr(i)};
                data_cell(i,4)=MaxMVA_ctg_cr(i);
                data_cell(i,5)={SIp_cr(i)};
                data_cell(i,6)={SIv_cr(i)};
                
            end
            
            app.UITable_CtgLineParamsCr.Data=data_cell;
            app.UITable_CtgLineParamsCr.RowName={'1-2','1-3','2-3',...
                '2-4','3-4'};
            
            app.SysAMWCOEditFieldCr.Value=SysAMWCO_cr;
            app.TSIEditFieldCr.Value=TSI_cr;
            
            %Comparison Results--------------------------------
            
            app.UITable_CompCtgLineParamsAbs.Data=[comp_AMWCO_abs,...
                comp_APCO_abs,comp_MaxMVA_abs,comp_SIp_abs,comp_SIv_abs];
            app.UITable_CompCtgLineParamsAbs.RowName={'1-2','1-3',...
                '2-3','2-4','3-4'};
            
            app.UITable_CompCtgLineParamsPct.Data=[comp_AMWCO_pct,...
                comp_APCO_pct,comp_MaxMVA_pct,comp_SIp_pct,comp_SIv_pct];
            app.UITable_CompCtgLineParamsPct.RowName={'1-2','1-3',...
                '2-3','2-4','3-4'};            
                       
            app.SysAMWCOEditFieldAbs.Value=comp_SysAMWCO_abs;
            app.SysAMWCOEditFieldPct.Value=comp_SysAMWCO_pct{1,1};
            
            app.TSIEditFieldAbs.Value=comp_TSI_abs;
            app.TSIEditFieldPct.Value=comp_TSI_pct{1,1};
            
            %Pie Charts----------------------------------------
            
            line_terminal_1=[1;1;2;2;3];
            line_terminal_2=[2;3;3;4;4];
            h=tiledlayout(app.BaseTabPie,2,3);
            
            for i=1:1:5                
                ax=nexttile(h);                
                if (Sm(i)<Plim(i))                    
                    pie(ax,[Sm(i)/Plim(i),1-Sm(i)/Plim(i)],...
                        {'Loading','Spare'});
                    colormap(ax,[1 0 0;0.5 0.5 0.5]);%red,grey                     
                else                    
                    pie(ax,1,{'Loading'});
                    colormap(ax,[1 0 0]);%red                     
                end                
                str=strcat('Line ',num2str(line_terminal_1(i))...
                    ,'-',num2str(line_terminal_2(i)),': ',...
                    num2str(Sm(i)/Plim(i)*100),'%');
                title(ax,str);
            end 
            
            h=tiledlayout(app.AsCtgResourceTabPie,2,3);
            
            for i=1:1:5                
                ax=nexttile(h);                
                if (Sm_cr(i)<Plim(i))                    
                    pie(ax,[Sm_cr(i)/Plim(i),1-Sm_cr(i)/Plim(i)],...
                        {'Loading','Spare'});
                    colormap(ax,[1 0 0;0.5 0.5 0.5]);%red,grey                     
                else                    
                    pie(ax,1,{'Loading'});
                    colormap(ax,[1 0 0]);%red                     
                end                
                str=strcat('Line ',num2str(line_terminal_1(i))...
                    ,'-',num2str(line_terminal_2(i)),': ',...
                    num2str(Sm_cr(i)/Plim(i)*100),'%');
                title(ax,str);
            end
            
            %Bar Graphs----------------------------------------
            
            lines={'1-2','1-3','2-3','2-4','3-4',...
                '2-1','3-1','3-2','4-2','4-3'};
            
            y=[];
            for i=1:1:5            
                y=[y;abs(P(i)),abs(Q(i)),abs(S(i))];            
            end
            for i=1:1:5            
                y=[y;abs(Pr(i)),abs(Qr(i)),abs(Sr(i))];            
            end
            yaxis_top_base=0.5+max(y,[],'all');
            
            y_cr=[];
            for i=1:1:5            
                y_cr=[y_cr;abs(P_cr(i)),abs(Q_cr(i)),abs(S_cr(i))];            
            end
            for i=1:1:5            
                y_cr=[y_cr;abs(Pr_cr(i)),abs(Qr_cr(i)),abs(Sr_cr(i))];            
            end
            yaxis_top_cr=0.5+max(y,[],'all');
            
            yaxis_top=max(yaxis_top_cr,yaxis_top_base);
           
            h=bar(app.UIAxesBase,y);
            xticklabels(app.UIAxesBase,lines);
            axis(app.UIAxesBase,[0.5 10.5 0 yaxis_top]);
            title(app.UIAxesBase,'Line Loading with EVs as Loads');
            xlabel(app.UIAxesBase,'Line');
            ylabel(app.UIAxesBase,'p.u.');
            set(h,{'DisplayName'},{'P','Q','S'}');
            legend(app.UIAxesBase);            
                    
            h=bar(app.UIAxesCr,y_cr);
            xticklabels(app.UIAxesCr,lines);
            axis(app.UIAxesCr,[0.5 10.5 0 yaxis_top]);
            title(app.UIAxesCr,...
                'Line Loading with EVs as CTG Resources');
            xlabel(app.UIAxesCr,'Line');
            ylabel(app.UIAxesCr,'p.u.');
            set(h,{'DisplayName'},{'P','Q','S'}');
            legend(app.UIAxesCr);
            
        end
        
        function Ybus = MakeYbus(app,Zmat)
            
            [nBus,~]=size(Zmat);
            Ybus=zeros(nBus);
            
            %Find diagonal entries of Ybus
            for r=1:1:nBus
                row=Zmat(r,:);
                diagonal_element=0;
                for c=1:1:nBus                
                        impedance=row(c);
                        admittance=MakeY(app,impedance);
                        diagonal_element=diagonal_element+admittance;                         
                end
                diagonal_element=diagonal_element+Zmat(r,r);%accounts for capacitances
                Ybus(r,r)=diagonal_element;
            end
            
            %Find off-diagonal entries of Ybus
            for r=1:1:nBus
                row=Zmat(r,:);
                for c=1:1:nBus
                    if(c~=r && Ybus(r,c)==0)
                        impedance=row(c);
                        admittance=MakeY(app,impedance);
                        Ybus(r,c)=-admittance;            
                    end
                end    
            end
            
        end
        
        function admittance = MakeY(app,impedance)
            
            j=sqrt(-1);
            r=abs(real(impedance));
            x=abs(imag(impedance));
            
            if(abs(impedance)~=0)
                admittance=r/(r^2+x^2)-j*x/(r^2+x^2);
            else 
                admittance=0;
            end
            
        end
        
        function f = EvaluateF(app,x,G,B,Pd,Qd,Pg,Qg,EVd)
            
            Th2=x(1);
            V2=x(4);
            Th3=x(2);
            V3=x(5);
            Th4=x(3);
            
            f2p=V2*(G(2,1)*cos(Th2)+B(2,1)*sin(Th2))+V2^2*G(2,2)+...
                V2*V3*(G(2,3)*cos(Th2-Th3)+B(2,3)*sin(Th2-Th3))+...
                V2*(G(2,4)*cos(Th2-Th4)+B(2,4)*sin(Th2-Th4))-...
                Pg(2)+Pd(2)+EVd(2);
            
            f3p=V3*(G(3,1)*cos(Th3)+B(3,1)*sin(Th3))+V3^2*G(3,3)+...
                V3*V2*(G(3,2)*cos(Th3-Th2)+B(3,2)*sin(Th3-Th2))+...
                V3*(G(3,4)*cos(Th3-Th4)+B(3,4)*sin(Th3-Th4))-...
                Pg(3)+Pd(3)+EVd(3);
            
            f4p=(G(4,1)*cos(Th4)+B(4,1)*sin(Th4))+G(4,4)+...
                V3*(G(4,3)*cos(Th4-Th3)+B(4,3)*sin(Th4-Th3))+...
                V2*(G(4,2)*cos(Th4-Th2)+B(4,2)*sin(Th4-Th2))-...
                Pg(4)+Pd(4)+EVd(4);
            
            f2q=V2*(G(2,1)*sin(Th2)-B(2,1)*cos(Th2))-V2^2*B(2,2)+...
                V2*V3*(G(2,3)*sin(Th2-Th3)-B(2,3)*cos(Th2-Th3))+...
                V2*(G(2,4)*sin(Th2-Th4)-B(2,4)*cos(Th2-Th4))-...
                Qg(2)+Qd(2);
            
            f3q=V3*(G(3,1)*sin(Th3)-B(3,1)*cos(Th3))-V3^2*B(3,3)+...
                V3*V2*(G(3,2)*sin(Th3-Th2)-B(3,2)*cos(Th3-Th2))+...
                V3*(G(3,4)*sin(Th3-Th4)-B(3,4)*cos(Th3-Th4))-...
                Qg(3)+Qd(3);
            
            f=[f2p;f3p;f4p;f2q;f3q];
            
        end
        
        function Jacobian = EvaluateJacobian(app,x,G,B)
            
            Th2=x(1);
            V2=x(4);
            Th3=x(2);
            V3=x(5);
            Th4=x(3);
            
            %P2--------------------------------------------------
            
            dP2_dTh2=V2*(-G(2,1)*sin(Th2)+B(2,1)*cos(Th2))+...
                V2*V3*(-G(2,3)*sin(Th2-Th3)+B(2,3)*cos(Th2-Th3))+...
                V2*(-G(2,4)*sin(Th2-Th4)+B(2,4)*cos(Th2-Th4));
            
            dP2_dTh3=V2*V3*(G(2,3)*sin(Th2-Th3)-B(2,3)*cos(Th2-Th3));
            
            dP2_dTh4=V2*(G(2,4)*sin(Th2-Th4)-B(2,4)*cos(Th2-Th4));
            
            dP2_dV2=(G(2,1)*cos(Th2)+B(2,1)*sin(Th2))+V2*2*G(2,2)+...
                V3*(G(2,3)*cos(Th2-Th3)+B(2,3)*sin(Th2-Th3))+...
                (G(2,4)*cos(Th2-Th4)+B(2,4)*sin(Th2-Th4));
            
            dP2_dV3=V3*(G(2,3)*cos(Th2-Th3)+B(2,3)*sin(Th2-Th3));
             
            %P3--------------------------------------------------
            
            dP3_dTh2=V3*V2*(G(3,2)*sin(Th3-Th2)-B(3,2)*cos(Th3-Th2));                
            
            dP3_dTh3=V3*(-G(3,1)*sin(Th3)+B(3,1)*cos(Th3))+...
                V3*V2*(-G(3,2)*sin(Th3-Th2)+B(3,2)*cos(Th3-Th2))+...
                V3*(-G(3,4)*sin(Th3-Th4)+B(3,4)*cos(Th3-Th4));
            
            dP3_dTh4=V3*(G(3,4)*sin(Th3-Th4)-B(3,4)*cos(Th3-Th4));
            
            dP3_dV2=V3*(G(3,2)*cos(Th3-Th2)+B(3,2)*sin(Th3-Th2));
            
            dP3_dV3=(G(3,1)*cos(Th3)+B(3,1)*sin(Th3))+V3*2*G(3,3)+...
                V2*(G(3,2)*cos(Th3-Th2)+B(3,2)*sin(Th3-Th2))+...
                (G(3,4)*cos(Th3-Th4)+B(3,4)*sin(Th3-Th4));
                
            %P4--------------------------------------------------
            
            dP4_dTh2=V2*(G(4,2)*sin(Th4-Th2)-B(4,2)*cos(Th4-Th2));                
            
            dP4_dTh3=V3*(G(4,3)*sin(Th4-Th3)-B(4,3)*cos(Th4-Th3));
            
            dP4_dTh4=(-G(4,1)*sin(Th4)+B(4,1)*cos(Th4))+...
                V3*(-G(4,3)*sin(Th4-Th3)+B(4,3)*cos(Th4-Th3))+...
                V2*(-G(4,2)*sin(Th4-Th2)+B(4,2)*cos(Th4-Th2));
            
            dP4_dV2=(G(4,2)*cos(Th4-Th2)+B(4,2)*sin(Th4-Th2));
            
            dP4_dV3=(G(4,3)*cos(Th4-Th3)+B(4,3)*sin(Th4-Th3));   
            
            %Q2--------------------------------------------------
            
            dQ2_dTh2=V2*(G(2,1)*cos(Th2)+B(2,1)*sin(Th2))+...
                V2*V3*(G(2,3)*cos(Th2-Th3)+B(2,3)*sin(Th2-Th3))+...
                V2*(G(2,4)*cos(Th2-Th4)+B(2,4)*sin(Th2-Th4));
            
            dQ2_dTh3=V2*V3*(-G(2,3)*cos(Th2-Th3)-B(2,3)*sin(Th2-Th3));
            
            dQ2_dTh4=V2*(-G(2,4)*cos(Th2-Th4)-B(2,4)*sin(Th2-Th4));
            
            dQ2_dV2=(G(2,1)*sin(Th2)-B(2,1)*cos(Th2))-V2*2*B(2,2)+...
                V3*(G(2,3)*sin(Th2-Th3)-B(2,3)*cos(Th2-Th3))+...
                (G(2,4)*sin(Th2-Th4)-B(2,4)*cos(Th2-Th4));
            
            dQ2_dV3=V2*(G(2,3)*sin(Th2-Th3)-B(2,3)*cos(Th2-Th3));
            
            %Q3--------------------------------------------------
            
            dQ3_dTh2=V3*V2*(-G(3,2)*cos(Th3-Th2)-B(3,2)*sin(Th3-Th2));
            
            dQ3_dTh3=V3*(G(3,1)*cos(Th3)+B(3,1)*sin(Th3))+...
                V3*V2*(G(3,2)*cos(Th3-Th2)+B(3,2)*sin(Th3-Th2))+...
                V3*(G(3,4)*cos(Th3-Th4)+B(3,4)*sin(Th3-Th4));
            
            dQ3_dTh4=V3*(-G(3,4)*cos(Th3-Th4)-B(3,4)*sin(Th3-Th4));
            
            dQ3_dV2=V3*(G(3,2)*sin(Th3-Th2)-B(3,2)*cos(Th3-Th2));
            
            dQ3_dV3=(G(3,1)*sin(Th3)-B(3,1)*cos(Th3))-V3*2*B(3,3)+...
                V2*(G(3,2)*sin(Th3-Th2)-B(3,2)*cos(Th3-Th2))+...
                (G(3,4)*sin(Th3-Th4)-B(3,4)*cos(Th3-Th4));
            
            Jacobian=[dP2_dTh2 dP2_dTh3 dP2_dTh4 dP2_dV2 dP2_dV3;
                dP3_dTh2 dP3_dTh3 dP3_dTh4 dP3_dV2 dP3_dV3;
                dP4_dTh2 dP4_dTh3 dP4_dTh4 dP4_dV2 dP4_dV3;
                dQ2_dTh2 dQ2_dTh3 dQ2_dTh4 dQ2_dV2 dQ2_dV3;
                dQ3_dTh2 dQ3_dTh3 dQ3_dTh4 dQ3_dV2 dQ3_dV3];
                        
        end
        
        function [x,flag] = FindX(app,x0,threshold,G,B,Pd,Qd,Pg,...
                Qg,EVd)
            
            epsilon=1e6;
            x=x0';
            ctr=0;
            flag=0;
            
            while epsilon>threshold
                Jacobian=EvaluateJacobian(app,x,G,B);
                f=EvaluateF(app,x,G,B,Pd,Qd,Pg,Qg,EVd);
                x=x-inv(Jacobian)*f;
                epsilon=max(abs(f),[],'all');
                ctr=ctr+1;
                if (ctr>25)
                    app.NoSolutionLamp.Color='red';
                    flag=1;
                    return
                end                
            end
            
        end
        
        function [P,Q,S,Pr,Qr,Sr,Sm] = FindPower(app,x,Zmat)
            
            Th2=x(1);
            V2=x(4);
            Th3=x(2);
            V3=x(5);
            Th4=x(3);
            
            V=[1;V2;V3;1];
            Th=[0;Th2;Th3;Th4];
            
            %Forward---------------------------------------------
            
            %P---------------------------------------------------
            
            Y12=MakeY(app,Zmat(1,2));
            P12=V(1)^2*real(Y12)-V(1)*V(2)*(real(Y12)*...
                cos(Th(1)-Th(2))+imag(Y12)*sin(Th(1)-Th(2)));
            
            Y13=MakeY(app,Zmat(1,3));
            P13=V(1)^2*real(Y13)-V(1)*V(3)*(real(Y13)*...
                cos(Th(1)-Th(3))+imag(Y13)*sin(Th(1)-Th(3)));
            
            Y23=MakeY(app,Zmat(2,3));
            P23=V(2)^2*real(Y23)-V(2)*V(3)*(real(Y23)*...
                cos(Th(2)-Th(3))+imag(Y23)*sin(Th(2)-Th(3)));
            
            Y24=MakeY(app,Zmat(2,4));
            P24=V(2)^2*real(Y24)-V(2)*V(4)*(real(Y24)*...
                cos(Th(2)-Th(4))+imag(Y24)*sin(Th(2)-Th(4)));
            
            Y34=MakeY(app,Zmat(3,4));
            P34=V(3)^2*real(Y34)-V(3)*V(4)*(real(Y34)*...
                cos(Th(3)-Th(4))+imag(Y34)*sin(Th(3)-Th(4)));
            
            P=[P12;P13;P23;P24;P34];
            
            %Q----------------------------------------------------
            
            Q12=-V(1)^2*imag(Y12)-V(1)*V(2)*(real(Y12)*...
                sin(Th(1)-Th(2))-imag(Y12)*cos(Th(1)-Th(2)));
            
            Q13=-V(1)^2*imag(Y13)-V(1)*V(3)*(real(Y13)*...
                sin(Th(1)-Th(3))-imag(Y13)*cos(Th(1)-Th(3)));
            
            Q23=-V(2)^2*imag(Y23)-V(2)*V(3)*(real(Y23)*...
                sin(Th(2)-Th(3))-imag(Y23)*cos(Th(2)-Th(3)));
            
            Q24=-V(2)^2*imag(Y24)-V(2)*V(4)*(real(Y24)*...
                sin(Th(2)-Th(4))-imag(Y24)*cos(Th(2)-Th(4)));
            
            Q34=-V(3)^2*imag(Y34)-V(3)*V(4)*(real(Y34)*...
                sin(Th(3)-Th(4))-imag(Y34)*cos(Th(3)-Th(4)));
            
            Q=[Q12;Q13;Q23;Q24;Q34];
            
            S=sqrt(P.^2+Q.^2);
            
            %Reverse---------------------------------------------
            
            %P---------------------------------------------------
            
            P21=V(2)^2*real(Y12)-V(1)*V(2)*(real(Y12)*...
                cos(Th(2)-Th(1))+imag(Y12)*sin(Th(2)-Th(1)));            
            
            P31=V(3)^2*real(Y13)-V(1)*V(3)*(real(Y13)*...
                cos(Th(3)-Th(1))+imag(Y13)*sin(Th(3)-Th(1)));            
           
            P32=V(3)^2*real(Y23)-V(2)*V(3)*(real(Y23)*...
                cos(Th(3)-Th(2))+imag(Y23)*sin(Th(3)-Th(2)));            
            
            P42=V(4)^2*real(Y24)-V(2)*V(4)*(real(Y24)*...
                cos(Th(4)-Th(2))+imag(Y24)*sin(Th(4)-Th(2)));            
            
            P43=V(4)^2*real(Y34)-V(3)*V(4)*(real(Y34)*...
                cos(Th(4)-Th(3))+imag(Y34)*sin(Th(4)-Th(3)));
            
            Pr=[P21;P31;P32;P42;P43];
            
            %Q----------------------------------------------------
            
            Q21=-V(2)^2*imag(Y12)-V(1)*V(2)*(real(Y12)*...
                sin(Th(2)-Th(1))-imag(Y12)*cos(Th(2)-Th(1)));
            
            Q31=-V(3)^2*imag(Y13)-V(1)*V(3)*(real(Y13)*...
                sin(Th(3)-Th(1))-imag(Y13)*cos(Th(3)-Th(1)));
            
            Q32=-V(3)^2*imag(Y23)-V(2)*V(3)*(real(Y23)*...
                sin(Th(3)-Th(2))-imag(Y23)*cos(Th(3)-Th(2)));
            
            Q42=-V(4)^2*imag(Y24)-V(2)*V(4)*(real(Y24)*...
                sin(Th(4)-Th(2))-imag(Y24)*cos(Th(4)-Th(2)));
            
            Q43=-V(4)^2*imag(Y34)-V(3)*V(4)*(real(Y34)*...
                sin(Th(4)-Th(3))-imag(Y34)*cos(Th(4)-Th(3)));
            
            Qr=[Q21;Q31;Q32;Q42;Q43];            
                        
            Sr=sqrt(Pr.^2+Qr.^2);
            
            Sm=max([S';Sr'])';
            
        end
        
        function [AMWCOs,APCOs,SysAMWCO,TSI,SIv,SIp,MaxMVA_pct,...
                MaxMVA_ctg,flag] = RunContengencies(app,Zmat,x0,Plim,...
                wV2,wV3,dV2,dV3,wP,EVloading,Pd,Qd,Pg,Qg)
            %{
                x0: flat start x vector
                Plim: column vector of line limits
                    [1-2;1-3;2-3;2-4;3-4]
                wV2, wV3, dV2, dV3: individual quantities
                wP: column vector of line SIp weights in same
                    order as Plim
                Evloading: column vector of EV loads at each bus.
                    Positive if not considering ctg potential,
                    negative otherwise
            
                %}
                
            flag=0;
            
            line_terminal_1=[1;1;2;2;3];
            line_terminal_2=[2;3;3;4;4];
            
            APCOs=zeros(5,1);
            MaxMVA_pct=zeros(5,1);
            MaxMVA_ctg=cell(5,1);
            CTGs={'1-2','1-3','2-3','2-4','3-4'};
            SIv=zeros(5,1);SIp=zeros(5,1);
            AMWCOs=zeros(5,1);
            SysAMWCO=0;
            TSI=0;
                        
            for i=1:1:5
                
                Zmat_copy=Zmat;
                Zmat_copy(line_terminal_1(i),line_terminal_2(i))=0;
                Zmat_copy(line_terminal_2(i),line_terminal_1(i))=0;
                
                Ybus=MakeYbus(app,Zmat_copy);
                G=real(Ybus);B=imag(Ybus);
                
                [x,flag]=FindX(app,x0,1e-3,G,B,Pd,Qd,Pg,Qg,EVloading);
                
                if (flag)
                    return
                end
                
                [~,~,~,P,~,~,S]=FindPower(app,x,Zmat_copy);                
                              
                for j=1:1:5 %lines
                    
                    limit=Plim(j);
                    if (S(j)>limit)
                        APCOs(j)=APCOs(j)+(S(j)/limit*100-100);
                    end
                    
                    if (S(j)/limit*100>MaxMVA_pct(j))
                        MaxMVA_pct(j)=S(j)/limit*100;
                        MaxMVA_ctg(j)=CTGs(i);
                    end                     
                    
                end
                
                V2=x(4);
                V3=x(5);
                
                SIv(i)=1/2*(wV2*((abs(V2)-1)/dV2)^2+...
                    wV3*((abs(V3)-1)/dV3)^2);%for busses
                
                SIp(i)=1/2*(wP(1)*(P(1)/Plim(1))^2+...
                    wP(2)*(P(2)/Plim(2))^2+...
                    wP(3)*(P(3)/Plim(3))^2+...
                    wP(4)*(P(4)/Plim(4))^2+...
                    wP(5)*(P(5)/Plim(5))^2);%for branches                
                
            end
            
            AMWCOs=APCOs.*Plim;
            SysAMWCO=sum(AMWCOs,'all');
            TSI=SysAMWCO/5;
            
        end
        
        function [mag,pct] = MakeComparisons(app,EVd,EVcr)
            
            mag=EVd-EVcr;
            [r,~]=size(EVd);
            pct=cell(r,1);
            
            for i=1:1:r
                
                if (EVd(i)==0)
                    pct(i)={'N/A'};
                else
                    value=(EVd(i)-EVcr(i))/EVd(i)*100;
                    pct(i)={value};
                end
                
            end
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            
            app.ProgramRunningLamp.Color='green';
            app.NoSolutionLamp.Color='black';pause(0.1);
            
            app.UITable_PwrBase.clo;
            app.UITable_CtgLineParams.clo;
            app.SysAMWCOEditField.clo;
            app.TSIEditField.clo; 
            
            app.UITable_PwrCr.clo;
            app.UITable_CtgLineParamsCr.clo;
            app.SysAMWCOEditFieldCr.clo;
            app.TSIEditFieldCr.clo; 
            
            app.UITable_CompCtgLineParamsPct.clo;
            app.UITable_CompCtgLineParamsAbs.clo;
            app.SysAMWCOEditFieldAbs.clo;
            app.TSIEditFieldAbs.clo;
            app.SysAMWCOEditFieldPct.clo;
            app.TSIEditFieldPct.clo;
            
            app.BaseTabPie.clo;
            app.AsCtgResourceTabPie.clo;
            
            app.UIAxesBase.cla;
            app.UIAxesCr.cla;
            
            EVloading=[0;
                app.Bus2EditField.Value;
                app.Bus3EditField.Value;
                app.Bus4EditField.Value];
            wV2=app.wforV2EditField.Value;
            wV3=app.wforV3EditField.Value;
            dV2=app.deltaforV2EditField.Value;
            dV3=app.deltaforV3EditField.Value;
            wP=[app.EditField12.Value;
                app.EditField13.Value;
                app.EditField23.Value;
                app.EditField24.Value;
                app.EditField34.Value];
            
            Main(app,EVloading,wV2,wV3,dV2,dV3,wP);
            
            app.ProgramRunningLamp.Color='black';pause(0.1);         
            
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.MainTab = uitab(app.TabGroup);
            app.MainTab.Title = 'Main';
            app.ProgramRunningLampLabel = uilabel(app.MainTab);
            app.ProgramRunningLampLabel.Text = 'Program Running';
            app.ProgramRunningLamp = uilamp(app.MainTab);
            app.RunButton = uibutton(app.MainTab, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.EVLoadinginpuatEachBusPanel = uipanel(app.MainTab);
            app.EVLoadinginpuatEachBusPanel.Title = 'EV Loading in p.u. at Each Bus';
            app.Bus2EditFieldLabel = uilabel(app.EVLoadinginpuatEachBusPanel);
            app.Bus2EditFieldLabel.Text = 'Bus 2';
            app.Bus2EditField = uieditfield(app.EVLoadinginpuatEachBusPanel, 'numeric');
            app.Bus2EditField.Limits = [0 Inf];
            app.Bus2EditField.Value = 0.25;
            app.Bus3EditFieldLabel = uilabel(app.EVLoadinginpuatEachBusPanel);
            app.Bus3EditFieldLabel.Text = 'Bus 3';
            app.Bus3EditField = uieditfield(app.EVLoadinginpuatEachBusPanel, 'numeric');
            app.Bus3EditField.Limits = [0 Inf];
            app.Bus3EditField.Value = 0.35;
            app.Bus4EditFieldLabel = uilabel(app.EVLoadinginpuatEachBusPanel);
            app.Bus4EditFieldLabel.Text = 'Bus 4';
            app.Bus4EditField = uieditfield(app.EVLoadinginpuatEachBusPanel, 'numeric');
            app.Bus4EditField.Limits = [0 Inf];
            app.SIvParametersPanel = uipanel(app.MainTab);
            app.SIvParametersPanel.Title = 'SIv Parameters';
            app.wforV2EditFieldLabel = uilabel(app.SIvParametersPanel);
            app.wforV2EditFieldLabel.Text = 'w for V2';
            app.wforV2EditField = uieditfield(app.SIvParametersPanel, 'numeric');
            app.wforV2EditField.Limits = [0 Inf];
            app.wforV2EditField.Value = 1;
            app.wforV3EditFieldLabel = uilabel(app.SIvParametersPanel);
            app.wforV3EditFieldLabel.Text = 'w for V3';
            app.wforV3EditField = uieditfield(app.SIvParametersPanel, 'numeric');
            app.wforV3EditField.Limits = [0 Inf];
            app.wforV3EditField.Value = 1;
            app.deltaforV2EditFieldLabel = uilabel(app.SIvParametersPanel);
            app.deltaforV2EditFieldLabel.Text = 'delta for V2';
            app.deltaforV2EditField = uieditfield(app.SIvParametersPanel, 'numeric');
            app.deltaforV2EditField.Limits = [0 Inf];
            app.deltaforV2EditField.Value = 0.05;
            app.deltaforV3EditFieldLabel = uilabel(app.SIvParametersPanel);
            app.deltaforV3EditFieldLabel.Text = 'delta for V3';
            app.deltaforV3EditField = uieditfield(app.SIvParametersPanel, 'numeric');
            app.deltaforV3EditField.Limits = [0 Inf];
            app.deltaforV3EditField.Value = 0.05;
            app.SIpwParametersforEachLinePanel = uipanel(app.MainTab);
            app.SIpwParametersforEachLinePanel.Title = 'SIp w Parameters for Each Line';
            app.Label = uilabel(app.SIpwParametersforEachLinePanel);
            app.Label.Text = '1-2';
            app.EditField12 = uieditfield(app.SIpwParametersforEachLinePanel, 'numeric');
            app.EditField12.Limits = [0 Inf];
            app.EditField12.Value = 1;
            app.Label_2 = uilabel(app.SIpwParametersforEachLinePanel);
            app.Label_2.Text = '1-3';
            app.EditField13 = uieditfield(app.SIpwParametersforEachLinePanel, 'numeric');
            app.EditField13.Limits = [0 Inf];
            app.EditField13.Value = 1;
            app.Label_3 = uilabel(app.SIpwParametersforEachLinePanel);
            app.Label_3.Text = '2-3';
            app.EditField23 = uieditfield(app.SIpwParametersforEachLinePanel, 'numeric');
            app.EditField23.Limits = [0 Inf];
            app.EditField23.Value = 1;
            app.Label_4 = uilabel(app.SIpwParametersforEachLinePanel);
            app.Label_4.Text = '2-4';
            app.EditField24 = uieditfield(app.SIpwParametersforEachLinePanel, 'numeric');
            app.EditField24.Limits = [0 Inf];
            app.EditField24.Value = 1;
            app.Label_5 = uilabel(app.SIpwParametersforEachLinePanel);
            app.Label_5.Text = '3-4';
            app.EditField34 = uieditfield(app.SIpwParametersforEachLinePanel, 'numeric');
            app.EditField34.Limits = [0 Inf];
            app.EditField34.Value = 1;
            app.NoSolutionLampLabel = uilabel(app.MainTab);
            app.NoSolutionLampLabel.Text = 'No Solution';
            app.NoSolutionLamp = uilamp(app.MainTab);
            app.BaseResultsTab = uitab(app.TabGroup);
            app.BaseResultsTab.Title = 'Base Results';
            app.UITable_CtgLineParams = uitable(app.BaseResultsTab);
            app.UITable_CtgLineParams.ColumnName = {'AMWCO'; 'APCO'; 'Max MVA Over (%)'; 'Ctg for Max MVA Over'; 'SIp'; 'SIv'};
            app.UITable_CtgLineParams.RowName = {''};
            app.SysAMWCOEditFieldLabel = uilabel(app.BaseResultsTab);
            app.SysAMWCOEditFieldLabel.Text = 'SysAMWCO';
            app.SysAMWCOEditField = uieditfield(app.BaseResultsTab, 'numeric');
            app.SysAMWCOEditField.Editable = 'off';
            app.TSIEditFieldLabel = uilabel(app.BaseResultsTab);
            app.TSIEditFieldLabel.Text = 'TSI';
            app.TSIEditField = uieditfield(app.BaseResultsTab, 'numeric');
            app.TSIEditField.Editable = 'off';
            app.UITable_PwrBase = uitable(app.BaseResultsTab);
            app.UITable_PwrBase.ColumnName = {'P (pu)'; 'Q (pu)'; 'S (pu)'; 'Pr (pu)'; 'Qr (pu)'; 'Sr (pu)'};
            app.UITable_PwrBase.RowName = {''};
            app.AsCtgResourceResultsTab = uitab(app.TabGroup);
            app.AsCtgResourceResultsTab.Title = 'As Ctg Resource Results';
            app.UITable_PwrCr = uitable(app.AsCtgResourceResultsTab);
            app.UITable_PwrCr.ColumnName = {'P (pu)'; 'Q (pu)'; 'S (pu)'; 'Pr (pu)'; 'Qr (pu)'; 'Sr (pu)'};
            app.UITable_PwrCr.RowName = {''};
            app.UITable_CtgLineParamsCr = uitable(app.AsCtgResourceResultsTab);
            app.UITable_CtgLineParamsCr.ColumnName = {'AMWCO'; 'APCO'; 'Max MVA Over (%)'; 'Ctg for Max MVA Over'; 'SIp'; 'SIv'};
            app.UITable_CtgLineParamsCr.RowName = {''};
            app.SysAMWCOEditField_2Label = uilabel(app.AsCtgResourceResultsTab);
            app.SysAMWCOEditField_2Label.Text = 'SysAMWCO';
            app.SysAMWCOEditFieldCr = uieditfield(app.AsCtgResourceResultsTab, 'numeric');
            app.SysAMWCOEditFieldCr.Editable = 'off';
            app.TSIEditField_3Label = uilabel(app.AsCtgResourceResultsTab);
            app.TSIEditField_3Label.Text = 'TSI';
            app.TSIEditFieldCr = uieditfield(app.AsCtgResourceResultsTab, 'numeric');
            app.TSIEditFieldCr.Editable = 'off';
            app.ComparisonsTab = uitab(app.TabGroup);
            app.ComparisonsTab.Title = 'Comparisons';
            app.UITable_CompCtgLineParamsPct = uitable(app.ComparisonsTab);
            app.UITable_CompCtgLineParamsPct.ColumnName = {'AMWCO'; 'APCO'; 'Max MVA Over (%)'; 'SIp'; 'SIv'};
            app.UITable_CompCtgLineParamsPct.RowName = {''};
            app.UITable_CompCtgLineParamsAbs = uitable(app.ComparisonsTab);
            app.UITable_CompCtgLineParamsAbs.ColumnName = {'AMWCO'; 'APCO'; 'Max MVA Over (%)'; 'SIp'; 'SIv'};
            app.UITable_CompCtgLineParamsAbs.RowName = {''};
            app.SysAMWCOEditField_2Label_2 = uilabel(app.ComparisonsTab);
            app.SysAMWCOEditField_2Label_2.Text = 'SysAMWCO';
            app.SysAMWCOEditFieldAbs = uieditfield(app.ComparisonsTab, 'numeric');
            app.SysAMWCOEditFieldAbs.Editable = 'off';
            app.SysAMWCOLabel = uilabel(app.ComparisonsTab);
            app.SysAMWCOLabel.Text = 'SysAMWCO %';
            app.SysAMWCOEditFieldPct = uieditfield(app.ComparisonsTab, 'numeric');
            app.SysAMWCOEditFieldPct.Editable = 'off';
            app.TSILabel = uilabel(app.ComparisonsTab);
            app.TSILabel.Text = 'TSI';
            app.TSIEditFieldAbs = uieditfield(app.ComparisonsTab, 'numeric');
            app.TSIEditFieldAbs.Editable = 'off';
            app.TSILabel_2 = uilabel(app.ComparisonsTab);
            app.TSILabel_2.Text = 'TSI %';
            app.TSIEditFieldPct = uieditfield(app.ComparisonsTab, 'numeric');
            app.TSIEditFieldPct.Editable = 'off';
            app.EditField = uieditfield(app.ComparisonsTab, 'text');
            app.EditField.Editable = 'off';
            app.EditField.Value = '%';
            app.EditField_2 = uieditfield(app.ComparisonsTab, 'text');
            app.EditField_2.Editable = 'off';
            app.EditField_2.Value = '|*|';
            app.PieChartsTab = uitab(app.TabGroup);
            app.PieChartsTab.Title = 'Pie Charts';
            app.TabGroup2 = uitabgroup(app.PieChartsTab);
            app.BaseTabPie = uitab(app.TabGroup2);
            app.BaseTabPie.Title = 'Base';
            app.AsCtgResourceTabPie = uitab(app.TabGroup2);
            app.AsCtgResourceTabPie.Title = 'As Ctg Resource';
            app.BarGraphsTab = uitab(app.TabGroup);
            app.BarGraphsTab.Title = 'Bar Graphs';
            app.TabGroup3 = uitabgroup(app.BarGraphsTab);
            app.BaseTabBar = uitab(app.TabGroup3);
            app.BaseTabBar.Title = 'Base';
            app.UIAxesBase = uiaxes(app.BaseTabBar);
            title(app.UIAxesBase, 'Title')
            xlabel(app.UIAxesBase, 'X')
            ylabel(app.UIAxesBase, 'Y')
            app.AsCtgResourceTabBar = uitab(app.TabGroup3);
            app.AsCtgResourceTabBar.Title = 'As Ctg Resource';
            app.UIAxesCr = uiaxes(app.AsCtgResourceTabBar);
            title(app.UIAxesCr, 'Title')
            xlabel(app.UIAxesCr, 'X')
            ylabel(app.UIAxesCr, 'Y')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = UserInterfacePresetSystemRevised

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end